classdef WaveformEditor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        Panel       matlab.ui.container.Panel
        GridLayout  matlab.ui.container.GridLayout
    end

    
    properties (Access = private)
        SrcApp % Description
        PropertyList
        IsDatabase logical = false
        WaveformID int64
        ParameterAll struct
    end
    
    properties (Access = public)
        Waveform % Description
        WaveformLibrary WaveformLibrary
        WaveformListLibrary WaveformListLibrary
    end

    properties(Constant)
        PositiveParameter = ["Duration","Frequency","StartFrequency","StopFrequency"]
        ModulationParameter = ["AmplitudeModulation","FrequencyModulation","PhaseModulation"];
        NonnegativeParameter = ["RiseTime","FallTime","RampTime"]
        ExceptionWaveform = "LinearRamp"
    end
    
    methods (Access = private)
        
        function parameterEditFieldValueChanged(app,src,~)
            row = src.Layout.Row;
            app.Waveform.(app.PropertyList(row)) = src.Value;
        end

        function variableDropDownValueChanged(app,src,~)
            row = src.Layout.Row;
            val = string(src.Value);
            paraName = app.PropertyList(row);

            if val == "None"
                app.GridLayout.Children(3*row - 1).Enable = "on";
                app.Waveform.Scan(app.Waveform.Scan.ParameterName == paraName,:) = [];
            else
                app.GridLayout.Children(3*row - 1).Enable = "off";
                if any(app.Waveform.Scan.ParameterName == paraName)
                    app.Waveform.Scan(app.Waveform.Scan.ParameterName == paraName,:).VariableName = val;
                else
                    app.Waveform.Scan(end+1,:) = table(paraName,val,...
                        'VariableNames',{'ParameterName','VariableName'});
                end
            end
        end

        function wflDropDownValueChanged(app,src,~)
            row = src.Layout.Row;
            val = string(src.Value);
            paraName = app.GridLayout.Children(3*row - 2).Text;
            paraName = paraName(1:end-1);
            if val == "None"
                app.Waveform.(paraName) = WaveformList.empty;
            else
                app.Waveform.(paraName) = app.WaveformLibrary([app.WaveformLibrary.Name] == val);
            end
        end
        
        function save(app)
            for ii = 1:numel(app.PropertyList)
                app.Waveform.(app.PropertyList(ii)) = app.GridLayout.Children(3*ii-1).Value;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, wf, srcApp)
            %% Initialize connections
            if nargin == 3
                app.SrcApp = srcApp;
                app.WaveformLibrary = srcApp.WaveformLibrary;
                app.WaveformListLibrary = srcApp.WaveformListLibrary;
            else
                app.WaveformLibrary = WaveformLibrary;
                app.WaveformListLibrary = WaveformListLibrary;
            end
            
            %% This app can take different types of inputs
            if isstring(wf)
                wfName = wf;
                app.Waveform = feval(wfName);
            elseif isa(wf,"Waveform")
                wfName = class(wf);
                app.Waveform = wf;
            elseif isnumeric(wf)
                app.IsDatabase = true;
                app.WaveformID = wf;
            end

            %% Generate PropertyList
            if ~app.IsDatabase
                mc = metaclass(app.Waveform);
                p = mc.PropertyList;
                p = p(~([p.Dependent] | [p.Constant] | [p.Hidden]));
                p = string({p.Name});
                p(ismember(p,["SamplingRate","Scan"])) = [];
                if isa(app.Waveform,"ConstantTop")
                    p(ismember(p,["Frequency","Phase"]))=[];
                end
                if ismember(wfName,app.ExceptionWaveform)
                    p(ismember(p,["Offset","Amplitude","RiseTime","FallTime"]))=[];
                end
                p(ismember(p,app.ModulationParameter))=[];
                app.PropertyList = p;
            else
                app.ParameterAll = app.WaveformLibrary.readParameter(app.WaveformID);
                app.PropertyList = string(fields(app.ParameterAll.Parameter));
            end

            app.Panel.Title = wfName;
            for ii = 1:numel(p)
                app.GridLayout.RowHeight{end+1} = 20;
                uilabel(app.GridLayout,"Text",p(ii) + ":");
                ef = uieditfield(app.GridLayout,'numeric','ValueChangedFcn',@app.parameterEditFieldValueChanged);
                if ismember(p(ii),app.NonnegativeParameter)
                    ef.Limits = [0,inf];
                elseif ismember(p(ii),app.PositiveParameter)
                    ef.Limits = [0,inf];
                    ef.LowerLimitInclusive = 'off';
                end
                if ~isstring(wf)
                    if ~isempty(wf.(p(ii)))
                        ef.Value = wf.(p(ii));
                    end
                end
                uidropdown(app.GridLayout,'Items',{'None'},'ValueChangedFcn',@app.variableDropDownValueChanged);
            end
            if nargin == 3
                if string(app.SrcApp.ConcatMethodDropDown.Value) == "Sequential"
                    idx = find(p == "StartTime");
                    app.GridLayout.Children(idx*3).Enable = "off";
                    app.GridLayout.Children(idx*3-1).Enable = "off";
                end
                if ~isempty(srcApp.SrcApp)
                    if ~isstring(wf)
                        scan = wf.Scan;
                    end
                    varList = [srcApp.SrcApp.VariableList.Name];
                    for ii = 1:numel(p)
                        app.GridLayout.Children(ii*3).Items = ["None";varList];
                        scanVariable = scan(scan.ParameterName == p(ii),:).VariableName;
                        if ~isempty(scanVariable) && ismember(scanVariable,varList)
                            app.GridLayout.Children(ii*3).Value = scanVariable;
                            app.GridLayout.Children(ii*3 - 1).Enable = "off";
                        end
                    end
                end
            end

            if isa(app.Waveform,"ModulatedWaveform")
                if nargin == 3
                    app.WaveformLibrary = srcApp.WaveformLibrary;
                else
                    app.WaveformLibrary = loadVar("WaveformLibrary.mat","WaveformLibrary");
                end
                for ii = 1:numel(app.ModulationParameter)
                    app.GridLayout.RowHeight{end+1} = 20;
                    uilabel(app.GridLayout,"Text",app.ModulationParameter(ii) + ":");
                    dd = uidropdown(app.GridLayout,"Items",["None",app.WaveformLibrary.Name],"ValueChangedFcn",@app.wflDropDownValueChanged);
                    uilabel(app.GridLayout,"Text",""); % To ensure three columns
                    if ~isstring(wf)
                        if ~isempty(wf.(app.ModulationParameter(ii)))
                            dd.Value = wf.(app.ModulationParameter(ii)).Name;
                        end
                    end
                end
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.save
            if ~isempty(app.SrcApp)
                app.SrcApp.plot
            end
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.GridLayout = uigridlayout(app.Panel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WaveformEditor(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end