classdef WaveformListEditor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Panel_2                       matlab.ui.container.Panel
        UIAxes                        matlab.ui.control.UIAxes
        Panel                         matlab.ui.container.Panel
        SamplingRateEditField         matlab.ui.control.NumericEditField
        SamplingRateLabel             matlab.ui.control.Label
        WaveformPanel2                matlab.ui.container.Panel
        WaveformGrid                  matlab.ui.container.GridLayout
        PatchConstantEditField        matlab.ui.control.NumericEditField
        PatchConstantEditField_Label  matlab.ui.control.Label
        PatchMethodDropDown           matlab.ui.control.DropDown
        PatchMethodDropDown_Label     matlab.ui.control.Label
        ConcatMethodDropDown          matlab.ui.control.DropDown
        ConcatMethodDropDown_Label    matlab.ui.control.Label
        WaveformListNameDropDown      matlab.ui.control.DropDown
        WaveformListNameLabel         matlab.ui.control.Label
        TriggerAdvanceCheckBox        matlab.ui.control.CheckBox
        DeleteWaveformListButton      matlab.ui.control.Button
        AddWaveformStepButton         matlab.ui.control.Button
    end


    properties (Access = public)
        SrcApp % Description
        WaveformLibrary
        WaveformTypeList
    end

    properties (Constant)
        PlotNumberLimit = 1e7
    end

    methods (Access = private)

        function loadWaveformListProp(app)
            wfl = app.WaveformLibrary([app.WaveformLibrary.Name] == string(app.WaveformListNameDropDown.Value));
            if ~isempty(wfl)
                app.ConcatMethodDropDown.Value = wfl.ConcatMethod;
                app.PatchMethodDropDown.Value = wfl.PatchMethod;
                app.PatchConstantEditField.Value = wfl.PatchConstant;
                app.TriggerAdvanceCheckBox.Value = wfl.IsTriggerAdvance;
                app.SamplingRateEditField.Value = wfl.SamplingRate;
                for ii = 1:numel(wfl.WaveformOrigin)
                    app.addWaveformStep(string(class(wfl.WaveformOrigin{ii})))
                end
            end
            app.togglePatch;
            app.plot;
        end

        function addWaveformStep(app,typeName)
            app.WaveformGrid.RowHeight{end + 1} = 20;
            tpList = uidropdown(app.WaveformGrid,'Items',app.WaveformTypeList,...
                'ValueChangedFcn',@app.typeDropDownChanged);
            if nargin == 2
                tpList.Value = typeName;
            end
            uibutton(app.WaveformGrid, 'push','Text','Edit','ButtonPushedFcn',@app.editButtonPushed);
            uibutton(app.WaveformGrid, 'push','Text','Delete','ButtonPushedFcn',@app.deleteButtonPushed);      
        end

        function typeDropDownChanged(app,src,~)
            row = src.Layout.Row;
            typeName = src.Value;
            listName = string(app.WaveformListNameDropDown.Value);
            wfl = app.WaveformLibrary([app.WaveformLibrary.Name] == listName);
            wfo = wfl.WaveformOrigin{row};
            sr = wfo.SamplingRate;
            st = wfo.StartTime;
            du = wfo.Duration;
            % amp = wfo.Amplitude;
            % os = wfo.Offset;
            wfl.WaveformOrigin{row} = feval(typeName);
            wfo = wfl.WaveformOrigin{row};
            wfo.SamplingRate = sr;
            wfo.StartTime = st;
            wfo.Duration = du;
            % wfo.Amplitude = amp;
            % wfo.Offset = os;
            app.plot
        end

        function editButtonPushed(app,src,~)
            row = src.Layout.Row;
            wf = app.WaveformLibrary([app.WaveformLibrary.Name] == string(app.WaveformListNameDropDown.Value)).WaveformOrigin{row};
            WaveformEditor(wf,app)
        end

        function deleteButtonPushed(app,src,~)
            nRow = numel(app.WaveformGrid.RowHeight);
            nColumn = 3;
            row = src.Layout.Row;
            for ii = (row+1):nRow
                for jj = 1:nColumn
                    app.WaveformGrid.Children((ii-1)*nColumn + jj).Layout.Row = ...
                        app.WaveformGrid.Children((ii-1)*nColumn + jj).Layout.Row - 1;
                end
            end
            permuteIdx = [1:(row-1)*nColumn,(row*nColumn+1):nRow*nColumn,((row-1)*nColumn+1):row*nColumn];
            app.WaveformGrid.Children = app.WaveformGrid.Children(permuteIdx);
            delete(app.WaveformGrid.Children((end-nColumn+1) : end))
            app.WaveformGrid.RowHeight(end) = [];
            app.WaveformLibrary([app.WaveformLibrary.Name] == string(app.WaveformListNameDropDown.Value)).WaveformOrigin(row) = [];
            app.plot
        end

        function saveWaveform(app)
            if ~isempty(app.SrcApp)
                if string(app.SrcApp.ScanButton.Text) == "Stop"
                    return
                end
            end
            WaveformLibrary = app.WaveformLibrary;
            save(which("WaveformLibrary.mat"),"WaveformLibrary");
            if ~isempty(app.SrcApp)
                app.SrcApp.WaveformLibrary = WaveformLibrary;
            end
        end

        
        function togglePatch(app)
            if string(app.ConcatMethodDropDown.Value) == "Sequential"
                app.PatchMethodDropDown.Enable = "off";
                app.PatchConstantEditField.Enable = "off";
            else
                app.PatchMethodDropDown.Enable = "on";
                app.PatchConstantEditField.Enable = "on";
            end
        end
    end
    
    methods (Access = public)
        
        function disableButton(app)
            if isempty(app.SrcApp) || string(app.SrcApp.ScanButton.Text) == "Scan"
                return
            end
            for ii = 1:numel(app.UIFigure.Children)
                if app.UIFigure.Children(ii) ~= app.WaveformListNameDropDown
                    app.UIFigure.Children(ii).Enable = "off";
                end
            end
        end

        function enableButton(app)
            if app.UIFigure.Children(ii) ~= app.WaveformListNameDropDown
                app.UIFigure.Children(ii).Enable = "on";
            end
        end
        
        function plot(app)
            wfl = app.WaveformLibrary([app.WaveformLibrary.Name] == string(app.WaveformListNameDropDown.Value));
            try
            if ~isempty(app.SrcApp)
                varList = app.SrcApp.VariableList;
                for jj = 1:numel(wfl.WaveformOrigin)
                    wfo = wfl.WaveformOrigin{jj};
                    if isempty(wfo)
                        continue
                    end
                    scan = wfo.Scan;
                    for kk = 1:size(scan,1)
                        if ismember(scan.VariableName(kk),varList.Name)
                            wfo.(scan.ParameterName(kk)) = varList(varList.Name == scan.VariableName(kk),:).EquationValue;
                        end
                    end
                    if isa(wfo,"ModulatedWaveform")
                        modName = ["AmplitudeModulation","FrequencyModulation","PhaseModulation"];
                        for mm = 1:numel(modName)
                            if ~isempty(wfo.(modName(mm)))
                                for ll = 1:numel(wfo.(modName(mm)).WaveformOrigin)
                                    wfoMod = wfo.(modName(mm)).WaveformOrigin{ll};
                                    scan = wfoMod.Scan;
                                    for kk = 1:size(scan,1)
                                        if ismember(scan.VariableName(kk),varList.Name)
                                            wfoMod.(scan.ParameterName(kk)) = varList(varList.Name == scan.VariableName(kk),:).EquationValue;
                                        end
                                    end
                                end
                            end
                        end
                    end
                end
            end
            if numel(wfl.Sample) > app.PlotNumberLimit
                warning("Too many samples. Will reduce the sampling rate for plotting.")
                sr = wfl.SamplingRate;
                wfl.SamplingRate = round(wfl.SamplingRate * app.PlotNumberLimit / numel(wfl.Sample));
                wfl.plot(app.UIAxes);
                wfl.SamplingRate = sr;
            else
                wfl.plot(app.UIAxes);
            end
            catch me
                warning("Can not plot. Check your sampling rate!")
                disp(me)
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, srcApp)
            if nargin == 2
                app.SrcApp = srcApp;
                app.WaveformLibrary = srcApp.WaveformLibrary;
            else
                app.WaveformLibrary = loadVar("WaveformLibrary.mat","WaveformLibrary");
            end
            app.WaveformTypeList = getAllWaveform;
            wflName = [app.WaveformLibrary.Name];
            app.WaveformListNameDropDown.Items = [wflName,"New List"];
            app.loadWaveformListProp;
        end

        % Value changed function: WaveformListNameDropDown
        function WaveformListNameDropDownValueChanged(app, event)
            listName = string(app.WaveformListNameDropDown.Value);
            delete(app.WaveformGrid.Children(:))
            app.WaveformGrid.RowHeight = {};
            if listName ~= "New List"
                app.loadWaveformListProp
            else
                f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
                listName = inputdlg("Please enter the name of the new Waveform List.","New Waveform List");
                delete(f);
                if ~isempty(listName)
                    if ~ismember(listName,[app.WaveformLibrary.Name])
                        app.WaveformLibrary(end + 1) = WaveformList(listName);
                        wflName = [app.WaveformLibrary.Name];
                        app.WaveformListNameDropDown.Items = [wflName,"New List"];
                        app.WaveformListNameDropDown.Value = listName;
                        wgApp = get(findall(0, 'Tag', "WgControlPanel"), 'RunningAppInstance');
                        for ii = 1:numel(wgApp)
                            if iscell(wgApp)
                                wg = wgApp{ii};
                            else
                                wg = wgApp(ii);
                            end
                            row = find(wg.PropertyList == "WaveformListName") + 1;
                            for jj = 1:wg.WaveformGenerator.NChannel
                                column = jj + 1;
                                cDropDown = findGridChild(wg.ChannelGrid,row,column);
                                cDropDown.Items = ["None",wflName];
                            end
                        end
                    else
                        msgbox("The name has been used.")
                        app.WaveformListNameDropDown.Value = app.WaveformListNameDropDown.Items{1};
                    end
                else
                    app.WaveformListNameDropDown.Value = app.WaveformListNameDropDown.Items{1};
                end
                app.loadWaveformListProp;
            end

        end

        % Button pushed function: AddWaveformStepButton
        function AddWaveformStepButtonPushed(app, event)
            app.addWaveformStep("ConstantWave")
            app.WaveformLibrary([app.WaveformLibrary.Name] == string(app.WaveformListNameDropDown.Value)).WaveformOrigin{end + 1} = ConstantWave;
            app.plot
        end

        % Value changed function: ConcatMethodDropDown
        function ConcatMethodDropDownValueChanged(app, event)
            value = app.ConcatMethodDropDown.Value;
            app.WaveformLibrary([app.WaveformLibrary.Name] == string(app.WaveformListNameDropDown.Value)).ConcatMethod = value;
            app.togglePatch;
            app.plot
        end

        % Value changed function: PatchMethodDropDown
        function PatchMethodDropDownValueChanged(app, event)
            value = app.PatchMethodDropDown.Value;
            app.WaveformLibrary([app.WaveformLibrary.Name] == string(app.WaveformListNameDropDown.Value)).PatchMethod = value;
            app.plot
        end

        % Value changed function: PatchConstantEditField
        function PatchConstantEditFieldValueChanged(app, event)
            value = app.PatchConstantEditField.Value;
            app.WaveformLibrary([app.WaveformLibrary.Name] == string(app.WaveformListNameDropDown.Value)).PatchConstant = value;
            app.plot
        end

        % Value changed function: TriggerAdvanceCheckBox
        function TriggerAdvanceCheckBoxValueChanged(app, event)
            value = app.TriggerAdvanceCheckBox.Value;
            app.WaveformLibrary([app.WaveformLibrary.Name] == string(app.WaveformListNameDropDown.Value)).IsTriggerAdvance = value;
        end

        % Button pushed function: DeleteWaveformListButton
        function DeleteWaveformListButtonPushed(app, event)
            wfName = string(app.WaveformListNameDropDown.Value);
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
            answer = string(questdlg("Do you want to delete the waveform list ["+ wfName +"]?"));
            delete(f)
            if answer == "Yes"
                app.WaveformLibrary([app.WaveformLibrary.Name] == wfName) = [];
                app.WaveformListNameDropDown.Items = [[app.WaveformLibrary.Name],"New List"];
                app.WaveformListNameDropDown.Value = app.WaveformListNameDropDown.Items{1};
                app.loadWaveformListProp;
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.saveWaveform;
            delete(app)
        end

        % Value changed function: SamplingRateEditField
        function SamplingRateEditFieldValueChanged(app, event)
            value = app.SamplingRateEditField.Value;
            app.WaveformLibrary([app.WaveformLibrary.Name] == string(app.WaveformListNameDropDown.Value)).SamplingRate = value;
            app.plot
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Panel = uipanel(app.UIFigure);
            app.AddWaveformStepButton = uibutton(app.Panel, 'push');
            app.AddWaveformStepButton.ButtonPushedFcn = createCallbackFcn(app, @AddWaveformStepButtonPushed, true);
            app.AddWaveformStepButton.Text = 'Add Waveform Step';
            app.DeleteWaveformListButton = uibutton(app.Panel, 'push');
            app.DeleteWaveformListButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteWaveformListButtonPushed, true);
            app.DeleteWaveformListButton.Text = 'Delete Waveform List';
            app.TriggerAdvanceCheckBox = uicheckbox(app.Panel);
            app.TriggerAdvanceCheckBox.ValueChangedFcn = createCallbackFcn(app, @TriggerAdvanceCheckBoxValueChanged, true);
            app.TriggerAdvanceCheckBox.Text = 'Trigger Advance';
            app.WaveformListNameLabel = uilabel(app.Panel);
            app.WaveformListNameLabel.Text = 'Waveform List Name:';
            app.WaveformListNameDropDown = uidropdown(app.Panel);
            app.WaveformListNameDropDown.ValueChangedFcn = createCallbackFcn(app, @WaveformListNameDropDownValueChanged, true);
            app.ConcatMethodDropDown_Label = uilabel(app.Panel);
            app.ConcatMethodDropDown_Label.Text = 'Concat Method:';
            app.ConcatMethodDropDown = uidropdown(app.Panel);
            app.ConcatMethodDropDown.Items = {'Sequential', 'Simultaneous'};
            app.ConcatMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @ConcatMethodDropDownValueChanged, true);
            app.ConcatMethodDropDown.Value = 'Sequential';
            app.PatchMethodDropDown_Label = uilabel(app.Panel);
            app.PatchMethodDropDown_Label.Text = 'Patch Method:';
            app.PatchMethodDropDown = uidropdown(app.Panel);
            app.PatchMethodDropDown.Items = {'Continue', 'Constant'};
            app.PatchMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @PatchMethodDropDownValueChanged, true);
            app.PatchMethodDropDown.Value = 'Continue';
            app.PatchConstantEditField_Label = uilabel(app.Panel);
            app.PatchConstantEditField_Label.Text = 'Patch Constant:';
            app.PatchConstantEditField = uieditfield(app.Panel, 'numeric');
            app.PatchConstantEditField.ValueChangedFcn = createCallbackFcn(app, @PatchConstantEditFieldValueChanged, true);
            app.WaveformPanel2 = uipanel(app.Panel);
            app.WaveformGrid = uigridlayout(app.WaveformPanel2);
            app.SamplingRateLabel = uilabel(app.Panel);
            app.SamplingRateLabel.Text = 'Sampling Rate:';
            app.SamplingRateEditField = uieditfield(app.Panel, 'numeric');
            app.SamplingRateEditField.ValueChangedFcn = createCallbackFcn(app, @SamplingRateEditFieldValueChanged, true);
            app.SamplingRateEditField.Value = 1000000;
            app.Panel_2 = uipanel(app.UIFigure);
            app.UIAxes = uiaxes(app.Panel_2);
            xlabel(app.UIAxes, 'Time', 'Interpreter', 'latex')
            ylabel(app.UIAxes, 'Voltage', 'Interpreter', 'latex')
            zlabel(app.UIAxes, 'Z', 'Interpreter', 'latex')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WaveformListEditor(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end