classdef BecControlPanel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BECExperimentControlPanelUIFigure  matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        TabGroup                        matlab.ui.container.TabGroup
        RunOnsiteExperimentTab          matlab.ui.container.Tab
        PlotHistogramofScannedParameterButton  matlab.ui.control.Button
        PlotMixandAnimationButton       matlab.ui.control.Button
        FASTSTOPButton                  matlab.ui.control.Button
        HoldButton                      matlab.ui.control.StateButton
        RESUMEButton                    matlab.ui.control.Button
        DeletedRunParameterButton       matlab.ui.control.Button
        OpenDataPathButton              matlab.ui.control.Button
        STOPButton                      matlab.ui.control.Button
        PAUSEButton                     matlab.ui.control.Button
        STARTButton                     matlab.ui.control.Button
        RefreshButton                   matlab.ui.control.Button
        UpdateButton                    matlab.ui.control.Button
        LoadSavedExperimentTab          matlab.ui.container.Tab
        ServerButtonGroup               matlab.ui.container.ButtonGroup
        LocalButton                     matlab.ui.control.ToggleButton
        RemoteButton                    matlab.ui.control.ToggleButton
        KeyWordsEditField               matlab.ui.control.EditField
        KeyWordsEditFieldLabel          matlab.ui.control.Label
        HoldButton_3                    matlab.ui.control.StateButton
        RefreshButton_3                 matlab.ui.control.Button
        UnloadButton                    matlab.ui.control.Button
        OpenDataPathButton_3            matlab.ui.control.Button
        LoadButton                      matlab.ui.control.Button
        SearchButton                    matlab.ui.control.Button
        SerialEditField2                matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        SerialCheckBox                  matlab.ui.control.CheckBox
        SerialEditField                 matlab.ui.control.NumericEditField
        SerialLabel                     matlab.ui.control.Label
        TrialNameDropDown_2             matlab.ui.control.DropDown
        TrialNameLabel_2                matlab.ui.control.Label
        DataTable                       matlab.ui.control.Table
        EndDateDatePicker               matlab.ui.control.DatePicker
        EndDateLabel                    matlab.ui.control.Label
        StartDateDatePicker             matlab.ui.control.DatePicker
        StartDateLabel                  matlab.ui.control.Label
        RunLocalTestTab                 matlab.ui.container.Tab
        PlotMixandAnimationButton_2     matlab.ui.control.Button
        FASTSTOPButton_2                matlab.ui.control.Button
        HoldButton_2                    matlab.ui.control.StateButton
        RESUMEButton_2                  matlab.ui.control.Button
        secondsLabel                    matlab.ui.control.Label
        RunTimeIntervalEditField        matlab.ui.control.NumericEditField
        RunTimeIntervalEditFieldLabel   matlab.ui.control.Label
        SelectTestDataPathButton        matlab.ui.control.Button
        TestDataPathEditField           matlab.ui.control.EditField
        DeletedRunParameterButton_2     matlab.ui.control.Button
        OpenDataPathButton_2            matlab.ui.control.Button
        STOPButton_2                    matlab.ui.control.Button
        PAUSEButton_2                   matlab.ui.control.Button
        STARTButton_2                   matlab.ui.control.Button
        RefreshButton_2                 matlab.ui.control.Button
        UpdateButton_2                  matlab.ui.control.Button
        MoreFunctionsTab                matlab.ui.container.Tab
        CreateParallelPoolButton        matlab.ui.control.Button
        ResetImageAcquisitionButton     matlab.ui.control.Button
        AssignBecExptoWorkSpaceButton   matlab.ui.control.Button
        GridLayout2                     matlab.ui.container.GridLayout
        Panel_2                         matlab.ui.container.Panel
        WarningsandErrorsTextArea       matlab.ui.control.TextArea
        WarningsandErrorsLabel          matlab.ui.control.Label
        OpenCiceroOriginButton          matlab.ui.control.Button
        OpenSetConfigButton             matlab.ui.control.Button
        RunSetConfigButton              matlab.ui.control.Button
        OpenParameterConfigButton       matlab.ui.control.Button
        OpenBECExperimentConfigButton   matlab.ui.control.Button
        OpenROIConfigButton             matlab.ui.control.Button
        RunLogTextArea                  matlab.ui.control.TextArea
        RunLogLabel                     matlab.ui.control.Label
        BECExperimentPropertiesPanel    matlab.ui.container.Panel
        ScannedParameter2DropDown       matlab.ui.control.DropDown
        ScannedParameter2Label          matlab.ui.control.Label
        EditHardwareAssociationButton   matlab.ui.control.Button
        AveragingMethodDropDown         matlab.ui.control.DropDown
        AveragingMethodDropDownLabel    matlab.ui.control.Label
        PotentialConfigurationDropDown  matlab.ui.control.DropDown
        PotentialConfigurationLabel     matlab.ui.control.Label
        CloudCenterReferenceDropDown    matlab.ui.control.DropDown
        CloudCenterReferenceLabel       matlab.ui.control.Label
        DescriptionTextArea             matlab.ui.control.TextArea
        DescriptionTextAreaLabel        matlab.ui.control.Label
        AddNewParameterButton           matlab.ui.control.Button
        TabGroup2                       matlab.ui.container.TabGroup
        OdTab                           matlab.ui.container.Tab
        ODMinEditField                  matlab.ui.control.NumericEditField
        ODMaxLabel                      matlab.ui.control.Label
        ODMaxEditField                  matlab.ui.control.NumericEditField
        ODMinLabel                      matlab.ui.control.Label
        FringeRemovalMethodDropDown     matlab.ui.control.DropDown
        FringeRemovalMethodLabel        matlab.ui.control.Label
        ShowFringeRemovalDataCheckBox   matlab.ui.control.CheckBox
        ImagingTab                      matlab.ui.container.Tab
        ImagingMethodDropDown           matlab.ui.control.DropDown
        ImagingMethodDropDownLabel      matlab.ui.control.Label
        ImagingStageDropDown            matlab.ui.control.DropDown
        ImagingStageLabel               matlab.ui.control.Label
        AdTab                           matlab.ui.container.Tab
        times1013m2Label_2              matlab.ui.control.Label
        times1013m2Label                matlab.ui.control.Label
        ADMinEditField                  matlab.ui.control.NumericEditField
        ADMaxLabel                      matlab.ui.control.Label
        ADMaxEditField                  matlab.ui.control.NumericEditField
        ADMinLabel                      matlab.ui.control.Label
        ADMethodDropDown                matlab.ui.control.DropDown
        ADMethodLabel                   matlab.ui.control.Label
        DensityFitTab                   matlab.ui.container.Tab
        DensityFitMethodDropDown        matlab.ui.control.DropDown
        DensityFitMethodLabel           matlab.ui.control.Label
        AtomNumberTab                   matlab.ui.container.Tab
        AtomNumberFitMethodDropDown     matlab.ui.control.DropDown
        AtomNumberFitMethodDropDownLabel  matlab.ui.control.Label
        ShowNormalizedAtomNumberCheckBox  matlab.ui.control.CheckBox
        ShowThermalCheckBox             matlab.ui.control.CheckBox
        ShowRawCheckBox                 matlab.ui.control.CheckBox
        times106Label                   matlab.ui.control.Label
        AtomNumberMaxEditField          matlab.ui.control.NumericEditField
        AtomNumberMaxLabel              matlab.ui.control.Label
        TofTab                          matlab.ui.container.Tab
        CenterFitTab                    matlab.ui.container.Tab
        ModifyYThermalFitButton         matlab.ui.control.Button
        ModifyXThermalFitButton         matlab.ui.control.Button
        SaveCloudCenterCheckBox         matlab.ui.control.CheckBox
        CenterFitMethodDropDown         matlab.ui.control.DropDown
        CenterFitMethodDropDownLabel    matlab.ui.control.Label
        KapitzaDiracTab                 matlab.ui.container.Tab
        ScopeValueTab                   matlab.ui.container.Tab
        ScopeValueNameTextArea          matlab.ui.control.TextArea
        ScopeValueNameLabel             matlab.ui.control.Label
        AnalysisMethodsPanel            matlab.ui.container.Panel
        Tree                            matlab.ui.container.CheckBoxTree
        OdNode                          matlab.ui.container.TreeNode
        ImagingNode                     matlab.ui.container.TreeNode
        AdNode                          matlab.ui.container.TreeNode
        DensityFitNode                  matlab.ui.container.TreeNode
        AtomNumberNode                  matlab.ui.container.TreeNode
        TofNode                         matlab.ui.container.TreeNode
        CenterFitNode                   matlab.ui.container.TreeNode
        KapitzaDiracNode                matlab.ui.container.TreeNode
        ScopeValueNode                  matlab.ui.container.TreeNode
        SaveButton                      matlab.ui.control.Button
        ShowButton                      matlab.ui.control.Button
        RefreshButton_4                 matlab.ui.control.Button
        InitializeButton                matlab.ui.control.Button
        SaveAsNewTrialTypeButton        matlab.ui.control.Button
        AtomLabel                       matlab.ui.control.Label
        OverwriteTrialTypeButton        matlab.ui.control.Button
        ScannedParameterDropDown        matlab.ui.control.DropDown
        ScannedParameterLabel           matlab.ui.control.Label
        TrialNameDropDown               matlab.ui.control.DropDown
        TrialNameLabel                  matlab.ui.control.Label
        SerialNumberLabel               matlab.ui.control.Label
        AutoAcquireImagesCheckBox       matlab.ui.control.CheckBox
        ROIDropDown                     matlab.ui.control.DropDown
        ROILabel                        matlab.ui.control.Label
        AcquisitionDropDown             matlab.ui.control.DropDown
        AcquisitionLabel                matlab.ui.control.Label
        PreviewerButtonGroup            matlab.ui.container.ButtonGroup
        AtomicDensityButton             matlab.ui.control.ToggleButton
        OpticalDensityButton            matlab.ui.control.ToggleButton
        TimeLabel                       matlab.ui.control.Label
    end


    properties (Access = private)
        RepoPath % The path to the MuscleMuseum package
        IsMainComputer % If the computer is the main experiment computer
    end

    properties
        BecExp BecExp = BecExp.empty % Description
        IsStarted logical = false
        BecExpConfig
        BecExpLocalTestConfig
        RoiConfig
        AcquisitionConfig
        BecExpParameterUnit
        CloudCenter
        TrialConfig
    end

    methods (Access = private)

        function displayConfigProperty(app)
            % Configuration of the selected trial
            if app.IsMainComputer
                trialConfig = app.BecExpConfig(app.BecExpConfig.TrialName == app.TrialNameDropDown.Value,:);
            else
                trialConfig = app.BecExpLocalTestConfig(app.BecExpLocalTestConfig.TrialName == app.TrialNameDropDown.Value,:);
            end

            % Display general properties according to the configuration
            app.AtomLabel.Text = "Atom: " + trialConfig.AtomName;
            app.AcquisitionDropDown.Value = trialConfig.AcquisitionName;
            app.ROIDropDown.Value = trialConfig.RoiName;
            app.AutoAcquireImagesCheckBox.Value = trialConfig.IsAutoAcquire;
            app.DescriptionTextArea.Value = trialConfig.Description;
            app.FringeRemovalMethodDropDown.Value = trialConfig.FringeRemovalMethod;
            app.ImagingStageDropDown.Value = trialConfig.ImagingStage;
            app.ImagingMethodDropDown.Value = trialConfig.ImagingMethod;
            app.ADMethodDropDown.Value = trialConfig.AdMethod;
            app.DensityFitMethodDropDown.Value = trialConfig.DensityFitMethod;
            app.AtomNumberMaxEditField.Value = trialConfig.AtomNumberYLim;
            app.AtomNumberFitMethodDropDown.Value = trialConfig.AtomNumberFitMethod;
            app.ADMaxEditField.Value = trialConfig.AdCLim;
            app.ADMinEditField.Value = 0;
            app.ODMaxEditField.Value = trialConfig.OdCLim;
            app.ODMinEditField.Value = 0;
            app.CenterFitMethodDropDown.Value = trialConfig.CenterFitMethod;
            app.ScopeValueNameTextArea.Value = trialConfig.ScopeValueName;
            try
                app.AveragingMethodDropDown.Value=trialConfig.AveragingMethod;
            catch
                app.AveragingMethodDropDown.Value="StdErr";
            end

            % Handle second scanned parameter for 2D scans
            if isstring(trialConfig.ScannedParameter) && size(trialConfig.ScannedParameter, 2) == 2
                % 2D scan - set both parameters
                app.ScannedParameterDropDown.Value = trialConfig.ScannedParameter(1);
                app.ScannedParameter2DropDown.Value = trialConfig.ScannedParameter(2);
            else
                % 1D scan - set first parameter and "None" for second
                app.ScannedParameterDropDown.Value = trialConfig.ScannedParameter;
                app.ScannedParameter2DropDown.Value = "None";
            end


            % Display cloud center reference
            if ~ismissing(trialConfig.CloudCenterReference)
                app.CloudCenterReferenceDropDown.Value = trialConfig.CloudCenterReference;
            else
                app.CloudCenterReferenceDropDown.Value = "None";
            end

            % Display data analysis
            allnodes = findall(app.Tree,"Type","uitreenode");
            aMethods = rmmissing(["Od";"Imaging";"Ad";... %Od, Imaging and Ad as default analyses
                strtrim(split(trialConfig.AnalysisMethod,";"))]);
            app.Tree.CheckedNodes = allnodes(ismember(string({allnodes.Text}),aMethods));

            % Update TrialConfig
            app.TrialConfig = trialConfig;
        end

        function aMethods = getAnalysisMethod(app)
            % This function is for reformattomg the analysis methods
            aMethods = string({app.Tree.CheckedNodes.Text});
            aMethods(ismember(aMethods,["Od";"Imaging";"Ad"])) = [];
            if ~isempty(aMethods)
                aMethods = join(aMethods,";");
                aMethods = sortBecAnalysis(aMethods);
            else
                aMethods = string(missing);
            end
        end

        function setBecExpProperty(app)
            % Set previewers
            if app.PreviewerButtonGroup.SelectedObject == app.OpticalDensityButton
                app.BecExp.Od.toggle(true);
                app.BecExp.Ad.toggle(false);
            else
                app.BecExp.Od.toggle(false);
                app.BecExp.Ad.toggle(true);
            end

            % We do not want to have mix and animation plots when we
            % refresh during the experiments.
            app.BecExp.Od.Chart(1).IsEnabled = false;
            app.BecExp.Od.Chart(2).IsEnabled = false;
            app.BecExp.Ad.Chart(1).IsEnabled = false;
            app.BecExp.Ad.Chart(2).IsEnabled = false;

            % Show fringe removal plots.
            app.BecExp.Od.Gui(2).IsEnabled = app.ShowFringeRemovalDataCheckBox.Value;

            % Set if we want to save the cloud center
            if isprop(app.BecExp,'CenterFit')
                app.BecExp.CenterFit.IsSaveCenter = app.SaveCloudCenterCheckBox.Value;
            end

            % Set atom number plots display
            if isprop(app.BecExp,'AtomNumber')
                app.BecExp.AtomNumber.IsShowNormalized = app.ShowNormalizedAtomNumberCheckBox.Value;
                app.BecExp.AtomNumber.IsShowRaw = app.ShowRawCheckBox.Value;
                app.BecExp.AtomNumber.IsShowThermal = app.ShowThermalCheckBox.Value;
            end
        end

        function disableButton(app)
            % Disable unwanted buttons when running the experiment.
            app.AutoAcquireImagesCheckBox.Enable = "off";
            app.AcquisitionDropDown.Enable = "off";
            app.TrialNameDropDown.Enable = "off";
            app.ROIDropDown.Enable = "off";
            app.RunSetConfigButton.Enable = "off";
            app.LoadButton.Enable = "off";
        end

        function enableButton(app)
            % Enable button when finishing running the experiment.
            app.AutoAcquireImagesCheckBox.Enable = "on";
            app.AcquisitionDropDown.Enable = "on";
            app.TrialNameDropDown.Enable = "on";
            app.ROIDropDown.Enable = "on";
            app.RunSetConfigButton.Enable = "on";
        end

        function tOut = modifyConfig(app,tIn)
            propList = ["TrialName","Description","ScannedParameter","AnalysisMethod",...
                "OdCLim","FringeRemovalMethod","ImagingStage","ImagingMethod",...
                "IsAutoAcquire","AdMethod","AdCLim","AcquisitionName","RoiName",...
                "DensityFitMethod","AtomNumberYLim","AtomNumberFitMethod","CenterFitMethod","CloudCenterReference",...
                "AveragingMethod","ScopeValueName","WaveformAssociation","PhaseLockAssociation"];
            tOut = updateTableFromTable(tIn,app.TrialConfig,propList);
        end

        function displayBecExpProperty(app)
            % Display general properties
            app.ScannedParameterDropDown.Value = app.BecExp.ScannedParameter;
            app.AcquisitionDropDown.Value = app.BecExp.Acquisition.Name;
            app.ROIDropDown.Value = app.BecExp.Roi.Name;
            app.DescriptionTextArea.Value = app.BecExp.Description;
            app.TimeLabel.Text = "Time: " + string(app.BecExp.DateTime);
            app.SerialNumberLabel.Text = "Serial Number: " + string(app.BecExp.SerialNumber);
            if isempty(app.BecExp.CloudCenter)
                app.CloudCenterReferenceDropDown.Value = "None";
            else
                app.CloudCenterReferenceDropDown.Value = ...
                    app.CloudCenter(min(sum(abs(app.CloudCenter.Center - app.BecExp.CloudCenter),2)),:).TrialName;
            end

            % Choose Od or Ad previewer. Choose if we want to show Fringe
            % removal
            if app.BecExp.Od.Gui(1).IsEnabled
                app.PreviewerButtonGroup.SelectedObject = app.OpticalDensityButton;
            else
                app.PreviewerButtonGroup.SelectedObject = app.AtomicDensityButton;
            end
            app.ShowFringeRemovalDataCheckBox.Value = app.BecExp.Od.Gui(2).IsEnabled;

            % Display analysis properties
            allnodes = findall(app.Tree,"Type","uitreenode");
            app.Tree.CheckedNodes = allnodes(ismember(string({allnodes.Text}),app.BecExp.AnalysisMethod));
            app.FringeRemovalMethodDropDown.Value = app.BecExp.Od.FringeRemovalMethod;
            app.ODMinEditField.Value = app.BecExp.Od.CLim(1);
            app.ODMaxEditField.Value = app.BecExp.Od.CLim(2);
            app.ImagingStageDropDown.Value = app.BecExp.Imaging.ImagingStage;
            app.ImagingMethodDropDown.Value = app.BecExp.Imaging.ImagingMethod;
            app.ADMethodDropDown.Value = app.BecExp.Ad.AdMethod;
            app.ADMinEditField.Value = app.BecExp.Ad.CLim(1);
            app.ADMaxEditField.Value = app.BecExp.Ad.CLim(2);
            app.AutoAcquireImagesCheckBox.Value = app.BecExp.IsAutoAcquire;
            if isprop(app.BecExp,'DensityFit')
                app.DensityFitMethodDropDown.Value = app.BecExp.DensityFit.FitMethod;
            end
            if isprop(app.BecExp,'AtomNumber')
                app.AtomNumberMaxEditField.Value = app.BecExp.AtomNumber.YLim(2);
                app.AtomNumberFitMethodDropDown.Value = app.BecExp.AtomNumber.FitMethod;
                app.ShowRawCheckBox.Value = app.BecExp.AtomNumber.IsShowRaw;
                app.ShowThermalCheckBox.Value = app.BecExp.AtomNumber.IsShowThermal;
                app.ShowNormalizedAtomNumberCheckBox.Value = app.BecExp.AtomNumber.IsShowNormalized;
            end
            if isprop(app.BecExp,'CenterFit')
                app.CenterFitMethodDropDown.Value = app.BecExp.CenterFit.FitMethod;
            end
            if isprop(app.BecExp,'ScopeValue')
                app.ScopeValueNameTextArea.Value = strjoin(app.BecExp.ScopeValue.FullValueName,";");
            end
        end

        function updateCloudCenterList(app)
            load("CloudCenterData.mat","CloudCenter")
            app.CloudCenter = CloudCenter;
            app.CloudCenterReferenceDropDown.Items = ["None";CloudCenter.TrialName];
            app.CloudCenterReferenceDropDown.Value = "None";
        end

        function resetButton(app)
            % For some buttons we want to reset them to the default
            % settings after finishing an experiment
            app.ShowFringeRemovalDataCheckBox.Value = false;
            app.SaveCloudCenterCheckBox.Value = false;
            app.ShowNormalizedAtomNumberCheckBox.Value = true;
            app.ShowRawCheckBox.Value = true;
            app.ShowThermalCheckBox.Value = true;
        end

        function updateBecExpScannedParameterFromDropdowns(app)
            if isvalid(app.BecExp)
                param1 = string(app.ScannedParameterDropDown.Value);
                param2 = string(app.ScannedParameter2DropDown.Value);

                if param2 == "None"
                    % 1D scan - use only the first parameter
                    app.BecExp.ScannedParameter = param1;
                    app.BecExp.ScannedParameterUnit = ...
                        app.BecExpParameterUnit(app.BecExpParameterUnit.ScannedParameter == param1,:).ScannedParameterUnit;
                else
                    % 2D scan - use both parameters
                    app.BecExp.ScannedParameter = [param1, param2];

                    % Get units for both parameters
                    unit1 = app.BecExpParameterUnit(app.BecExpParameterUnit.ScannedParameter == param1,:).ScannedParameterUnit;
                    unit2 = app.BecExpParameterUnit(app.BecExpParameterUnit.ScannedParameter == param2,:).ScannedParameterUnit;
                    app.BecExp.ScannedParameterUnit = [unit1, unit2];
                end
                app.BecExp.refresh;
            end
        end

        function updateTrialConfigScannedParameterFromDropdowns(app)
            param1 = string(app.ScannedParameterDropDown.Value);
            param2 = string(app.ScannedParameter2DropDown.Value);

            if param2 == "None"
                % 1D scan - use only the first parameter
                app.TrialConfig.ScannedParameter = param1;
                app.TrialConfig.ScannedParameterUnit = ...
                    app.BecExpParameterUnit(app.BecExpParameterUnit.ScannedParameter == param1,:).ScannedParameterUnit;
            else
                % 2D scan - use both parameters
                app.TrialConfig.ScannedParameter = strmat2str([param1, param2]);

                % Get units for both parameters
                unit1 = app.BecExpParameterUnit(app.BecExpParameterUnit.ScannedParameter == param1,:).ScannedParameterUnit;
                unit2 = app.BecExpParameterUnit(app.BecExpParameterUnit.ScannedParameter == param2,:).ScannedParameterUnit;
                app.TrialConfig.ScannedParameterUnit = strmat2str([unit1, unit2]);
            end
        end
    end

    methods (Access = public)

        function displayLog(app,str,logType)
            % Display log in log box. [str] must be a string scalar.
            arguments
                app
                str string
                logType string = "normal"
            end

            t = datetime("now");
            t.Format = "HH:mm:ss";
            if logType == "normal"
                if str == " "
                    app.RunLogTextArea.Value = [app.RunLogTextArea.Value;str];
                else
                    app.RunLogTextArea.Value = [app.RunLogTextArea.Value;string(t) + " " + str];
                end
                scroll(app.RunLogTextArea, 'bottom')
            else
                app.WarningsandErrorsTextArea.Value = ...
                    [app.WarningsandErrorsTextArea.Value;string(t) + " " + logType + ": " + str];
                scroll(app.WarningsandErrorsTextArea, 'bottom')
                if logType == "error"
                    error(str)
                end
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %% Initialize property display
            load("Config.mat","BecExpConfig","BecExpLocalTestConfig",...
                "BecExpParameterUnit","AcquisitionConfig","RoiConfig",...
                "ComputerConfig")
            app.BecExpConfig = BecExpConfig;
            app.BecExpLocalTestConfig = BecExpLocalTestConfig;
            app.BecExpParameterUnit = BecExpParameterUnit;
            app.AcquisitionConfig = AcquisitionConfig;
            app.RoiConfig = RoiConfig;

            %% Check computer
            computerName = string(getenv('computername'));
            app.IsMainComputer = computerName == ComputerConfig.BecExpControlComputerName;

            %% Display config properties
            app.TrialNameDropDown.Items = sortBecExpTrialName(app.BecExpConfig.TrialName);
            app.TrialNameDropDown.Value = "Test";

            app.ScannedParameterDropDown.Items = sortBecExpParameter(app.BecExpParameterUnit.ScannedParameter);
            app.ScannedParameter2DropDown.Items = ["None"; sortBecExpParameter(app.BecExpParameterUnit.ScannedParameter)];
            app.ScannedParameter2DropDown.Value = "None";
            app.AcquisitionDropDown.Items = app.AcquisitionConfig.Name;
            app.ROIDropDown.Items = ["Full";app.RoiConfig.Name];
            app.ImagingStageDropDown.Items = app.BecExpConfig(1,:).ImagingStageList;

            app.updateCloudCenterList;
            app.displayConfigProperty;
            app.PreviewerButtonGroup.SelectedObject = app.AtomicDensityButton;
            app.ShowFringeRemovalDataCheckBox.Value = false;

            %% Initialize test
            app.RepoPath = ComputerConfig.RepoPath;
            app.TestDataPathEditField.Value = fullfile(app.RepoPath,"test","testData","sampleData");
            app.RunTimeIntervalEditField.Value = 5;

            %% Initialize loading
            app.EndDateDatePicker.Value = datetime("today");
            app.TrialNameDropDown_2.Items = ["All";sortBecExpTrialName(app.BecExpConfig.TrialName)];

            %% Initialize log box
            t = datetime("now");
            t.Format = "HH:mm:ss";
            if app.IsMainComputer
                str = ["This computer [" + computerName + "] is the main control computer.";...
                    "Loading onsite experiment configuration settings.";...
                    "Date: " + string(datetime("today"));...
                    "Time: " + string(t);...
                    "Default parent path: " + app.BecExpConfig.ParentPath(1);...
                    "Default database: " + app.BecExpConfig.DatabaseName(1);...
                    "Default database table: " + app.BecExpConfig.DatabaseTableName(1);...
                    "Cicero log origin path: " + app.BecExpConfig.CiceroLogOrigin(1)
                    ];
            else
                str = ["This computer [" + computerName + "] is not the main control computer. " + ...
                    "Onsite experiment will be disabled.";...
                    "Loading local test configuration settings.";...
                    "Date: " + string(datetime("today"));...
                    "Time: " + string(t);...
                    "Default parent path: " + app.BecExpLocalTestConfig.ParentPath(1);...
                    "Default database: " + app.BecExpLocalTestConfig.DatabaseName(1);...
                    "Default database table: " + app.BecExpLocalTestConfig.DatabaseTableName(1);...
                    "Cicero log origin path: " + app.BecExpLocalTestConfig.CiceroLogOrigin(1)
                    ];
                app.STARTButton.Enable = "off";
            end
            if strlength(string(app.RunLogTextArea.Value)) == 0
                app.RunLogTextArea.Value = str;
            else
                app.RunLogTextArea.Value = [app.RunLogTextArea.Value;" ";str];
            end

            %% Other settings
            app.SaveCloudCenterCheckBox.Value = false;

        end

        % Value changed function: TrialNameDropDown
        function TrialNameDropDownValueChanged(app, event)
            value = app.TrialNameDropDown.Value;
            if ~ismember(value,app.TrialNameDropDown.Items)
                app.TrialNameDropDown.Value = "Test";
            end
            app.displayConfigProperty
        end

        % Callback function: Tree
        function TreeCheckedNodesChanged(app, event)
            % Od, Imaging and Ad are always enabled
            allnodes = findall(app.Tree,"Type","uitreenode");
            default = allnodes(ismember(string({allnodes.Text}),["Od";"Imaging";"Ad"]));
            checkedNodes = app.Tree.CheckedNodes;
            checkedNodes = union(checkedNodes,default);
            app.Tree.CheckedNodes = checkedNodes;
            app.TrialConfig.AnalysisMethod = app.getAnalysisMethod;

            if isvalid(app.BecExp)
                aMethods = app.getAnalysisMethod;
                aMethods = strtrim(split(aMethods,";"));
                aMethods = rmmissing(aMethods);
                becExpAMethods = app.BecExp.AnalysisMethod;
                becExpAMethods(ismember(becExpAMethods,["Od";"Imaging";"Ad"])) = [];

                % add new methods
                newMethods = aMethods(~ismember(aMethods,becExpAMethods));
                app.BecExp.addAnalysis(newMethods);

                % close old methods plots
                oldMethods = becExpAMethods(~ismember(becExpAMethods,aMethods));
                app.BecExp.removeAnalysis(oldMethods);

                if ~isempty(newMethods)
                    newMethods = sortBecAnalysis(newMethods);
                    app.BecExp.refresh(newMethods(1))
                end

            end
        end

        % Button pushed function: OverwriteTrialTypeButton
        function OverwriteTrialTypeButtonPushed(app, event)
            trialName = string(app.TrialNameDropDown.Value);
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
            answer = questdlg("Do you want to overwrite the Trial type ["+ trialName +"]?");
            delete(f)
            answer = string(answer);
            if answer == "Yes"
                % BecExpConfig
                t = app.BecExpConfig(app.BecExpConfig.TrialName == trialName,:);
                t = app.modifyConfig(t);
                app.BecExpConfig(app.BecExpConfig.TrialName == trialName,:) = t;
                BecExpConfig = app.BecExpConfig;

                % BecExpLocalTestConfig
                t = app.BecExpLocalTestConfig(app.BecExpLocalTestConfig.TrialName == trialName,:);
                t = app.modifyConfig(t);
                app.BecExpLocalTestConfig(app.BecExpLocalTestConfig.TrialName == trialName,:) = t;
                BecExpLocalTestConfig = app.BecExpLocalTestConfig;

                % Save Config
                save(which("Config.mat"),"BecExpConfig","BecExpLocalTestConfig","-append")

                % BecExpType table
                t = readtable(which("becExpType.csv.xlsx"),'TextType','string');
                t = t(t.TrialName == trialName,:);
                t = app.modifyConfig(t);
                row = find(app.BecExpConfig.TrialName == trialName) + 1;
                range = "A" + num2str(row);
                writetable(t,which("becExpType.csv.xlsx"), 'Range',range,'WriteVariableNames',false);

                % Update TrialConfig
                if app.IsMainComputer
                    app.TrialConfig = app.BecExpConfig(app.BecExpConfig.TrialName == trialName,:);
                else
                    app.TrialConfig = app.BecExpLocalTestConfig(app.BecExpLocalTestConfig.TrialName == trialName,:);
                end
            end
        end

        % Button pushed function: SaveAsNewTrialTypeButton
        function SaveAsNewTrialTypeButtonPushed(app, event)
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
            trialName = inputdlg("Please enter the name of the new Trial type to be saved." + ...
                " Name can only contain alphabetic and numeric characters. " + ...
                "CamelCase recommended): ","Save as New Trial Type");
            delete(f);
            if ~isempty(trialName)
                trialName = string(trialName);
                if ~isAlphaNum(trialName)
                    error("The input name can only contain alphabetic and numeric characters.")
                elseif any(app.BecExpConfig.TrialName == trialName)
                    error("The input name has been used.")
                end

                % BecExpConfig
                t = app.BecExpConfig(app.BecExpConfig.TrialName == app.TrialNameDropDown.Value,:);
                t = app.modifyConfig(t);
                t.TrialName = trialName;
                app.BecExpConfig = [app.BecExpConfig;t];
                BecExpConfig = app.BecExpConfig;

                % BecExpLocalTestConfig
                t = app.BecExpLocalTestConfig(app.BecExpLocalTestConfig.TrialName == app.TrialNameDropDown.Value,:);
                t = app.modifyConfig(t);
                t.TrialName = trialName;
                app.BecExpLocalTestConfig = [app.BecExpLocalTestConfig;t];
                BecExpLocalTestConfig = app.BecExpLocalTestConfig;

                % Save config
                save(which("Config.mat"),"BecExpConfig","BecExpLocalTestConfig","-append")

                % BecExpType table
                t = readtable(which("becExpType.csv.xlsx"),'TextType','string');
                t = t(t.TrialName == app.TrialNameDropDown.Value,:);
                t = app.modifyConfig(t);
                t.TrialName = trialName;
                writetable(t,which("becExpType.csv.xlsx"), 'WriteMode','append');

                % Update app display
                trialNameList = app.TrialNameDropDown.Items;
                trialNameList = [trialNameList,{char(trialName)}];
                app.TrialNameDropDown.Items = trialNameList;
                app.TrialNameDropDown.Value = trialName;
                app.TrialNameDropDown_2.Items = [{'All'},trialNameList];

                % Update TrialConfig
                app.TrialConfig.TrialName = trialName;
            end
        end

        % Button pushed function: AddNewParameterButton
        function AddNewParameterButtonPushed(app, event)
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
            answer = inputdlg(["Enter parameter name:" ...
                , "Eneter parameter unit (LaTex format supported, no $$):"]);
            delete(f);
            answer = string(answer);
            if ~isempty(answer)
                ScannedParameter = answer(1);
                ScannedParameterUnit = answer(2);
                t = table(ScannedParameter,ScannedParameterUnit);
                app.BecExpParameterUnit = [app.BecExpParameterUnit;t];
                BecExpParameterUnit = app.BecExpParameterUnit;
                save(which("Config.mat"),"BecExpParameterUnit","-append");
                writetable(t,which("parameterUnit.csv.xlsx"), 'WriteMode','append');
                app.ScannedParameterDropDown.Items = sortBecExpParameter(app.BecExpParameterUnit.ScannedParameter);
                app.ScannedParameter2DropDown.Items = ["None"; sortBecExpParameter(app.BecExpParameterUnit.ScannedParameter)];
            end
        end

        % Button pushed function: SelectTestDataPathButton
        function SelectTestDataPathButtonPushed(app, event)
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
            folder = uigetdir();
            delete(f);
            if ischar(folder)
                app.TestDataPathEditField.Value = folder;
            end
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            if app.IsStarted
                return
            end
            app.IsStarted = true;

            app.displayLog(" ")
            app.displayLog("Starting onsite experiment...")

            % Change buttons
            app.STARTButton.Enable = "off";
            app.STOPButton.Enable = "on";
            app.FASTSTOPButton.Enable = "on";
            app.PAUSEButton.Enable = "on";
            app.UpdateButton.Enable = "on";
            app.HoldButton.Enable = "on";
            app.RefreshButton.Enable = "on";
            app.OpenDataPathButton.Enable = "on";
            app.DeletedRunParameterButton.Enable = "on";
            app.STARTButton_2.Enable = "off";
            app.PlotMixandAnimationButton.Enable = "on";
            app.disableButton

            % Construct BecExp
            trialName = app.TrialNameDropDown.Value;
            app.BecExp = BecExp(trialName,config = app.TrialConfig);
            app.setBecExpProperty;

            % Start exp
            app.BecExp.start
            app.TimeLabel.Text = "Time: " + string(app.BecExp.DateTime);
            app.SerialNumberLabel.Text = "Serial Number: " + string(app.BecExp.SerialNumber);
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            if ~app.IsStarted
                return
            end
            app.IsStarted = false;

            % Enable Od and Ad mix/animation when stop
            if app.PreviewerButtonGroup.SelectedObject == app.OpticalDensityButton
                app.BecExp.Od.Chart(1).IsEnabled = true;
                app.BecExp.Od.Chart(2).IsEnabled = true;
            else
                app.BecExp.Ad.Chart(1).IsEnabled = true;
                app.BecExp.Ad.Chart(2).IsEnabled = true;
            end

            % Stop and delete
            app.BecExp.stop;
            if isprop(app.BecExp,'CenterFit') % If Cloud center is saved, reset the list.
                if app.BecExp.CenterFit.IsSaveCenter
                    app.updateCloudCenterList;
                end
            end
            app.BecExp.delete;

            % Change buttons
            app.STARTButton.Enable = "on";
            app.STOPButton.Enable = "off";
            app.FASTSTOPButton.Enable = "off";
            app.PAUSEButton.Enable = "off";
            app.RESUMEButton.Enable = "off";
            app.UpdateButton.Enable = "off";
            app.HoldButton.Enable = "off";
            app.RefreshButton.Enable = "off";
            app.OpenDataPathButton.Enable = "off";
            app.DeletedRunParameterButton.Enable = "off";
            app.STARTButton_2.Enable = "on";
            app.PlotMixandAnimationButton.Enable = "off";
            app.enableButton;
            app.resetButton;

            app.displayLog("Onsite experiment is stopped.")
        end

        % Button pushed function: FASTSTOPButton
        function FASTSTOPButtonPushed(app, event)
            if ~app.IsStarted
                return
            end
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
            answer = questdlg("Do you want to stop with out refreshing and saving" ...
                + "Od/Ad mix and animation?",'Fast Stop','Yes','No','No');
            delete(f);
            answer = string(answer);
            if answer == "Yes"
                app.IsStarted = false;

                % Stop and delete
                app.BecExp.fastStop;
                if isprop(app.BecExp,'CenterFit') % If Cloud center is saved, reset the list.
                    if app.BecExp.CenterFit.IsSaveCenter
                        app.updateCloudCenterList;
                    end
                end
                app.BecExp.delete;

                % Change buttons
                app.STARTButton.Enable = "on";
                app.STOPButton.Enable = "off";
                app.FASTSTOPButton.Enable = "off";
                app.PAUSEButton.Enable = "off";
                app.RESUMEButton.Enable = "off";
                app.UpdateButton.Enable = "off";
                app.HoldButton.Enable = "off";
                app.RefreshButton.Enable = "off";
                app.OpenDataPathButton.Enable = "off";
                app.DeletedRunParameterButton.Enable = "off";
                app.STARTButton_2.Enable = "on";
                app.PlotMixandAnimationButton.Enable = "off";
                app.enableButton;
                app.resetButton;

                app.displayLog("Onsite experiment is stopped.")
            end
        end

        % Button pushed function: PAUSEButton
        function PAUSEButtonPushed(app, event)
            if isvalid(app.BecExp)
                app.BecExp.pause;
                app.RESUMEButton.Enable = "on";
                app.PAUSEButton.Enable = "off";
            end
        end

        % Button pushed function: RESUMEButton
        function RESUMEButtonPushed(app, event)
            if isvalid(app.BecExp)
                app.BecExp.resume;
                app.PAUSEButton.Enable = "on";
                app.RESUMEButton.Enable = "off";
            end
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            if isvalid(app.BecExp)
                app.BecExp.update
            end
        end

        % Value changed function: HoldButton
        function HoldButtonValueChanged(app, event)
            value = app.HoldButton.Value;
            if isvalid(app.BecExp)
                app.BecExp.IsHoldRefresh = value;
            end
            if value
                app.RefreshButton.Enable = "off";
            else
                app.RefreshButton.Enable = "on";
            end
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            if isvalid(app.BecExp)
                app.BecExp.refresh
            end
        end

        % Button pushed function: OpenDataPathButton
        function OpenDataPathButtonPushed(app, event)
            if isvalid(app.BecExp)
                winopen(app.BecExp.DataPath);
            end
        end

        % Button pushed function: DeletedRunParameterButton
        function DeletedRunParameterButtonPushed(app, event)
            if isvalid(app.BecExp)
                app.displayLog("Deleted run parameters: " + num2str(app.BecExp.DeletedRunParameterList))
            end
        end

        % Button pushed function: STARTButton_2
        function STARTButton_2Pushed(app, event)
            if app.IsStarted
                return
            end
            app.IsStarted = true;
            
            app.displayLog(" ")
            app.displayLog("Starting local test...")

            % Change buttons
            app.STARTButton_2.Enable = "off";
            app.STOPButton_2.Enable = "on";
            app.FASTSTOPButton_2.Enable = "on";
            app.PAUSEButton_2.Enable = "on";
            app.RunTimeIntervalEditField.Editable = "off";
            app.SelectTestDataPathButton.Enable = "off";
            app.TestDataPathEditField.Editable = "off";
            app.UpdateButton_2.Enable = "on";
            app.HoldButton_2.Enable = "on";
            app.RefreshButton_2.Enable = "on";
            app.OpenDataPathButton_2.Enable = "on";
            app.DeletedRunParameterButton_2.Enable = "on";
            app.STARTButton.Enable = "off";
            app.AutoAcquireImagesCheckBox.Value = false;
            app.PlotMixandAnimationButton_2.Enable = "on";
            app.disableButton;

            % Set test folders
            testCiceroLogOrigin = fullfile(app.RepoPath,"test","testData","testLogFiles");
            emptyFolder(testCiceroLogOrigin);
            createFolder(testCiceroLogOrigin);
            samplePath = string(app.TestDataPathEditField.Value);

            % Construct test BecExp
            trialName = app.TrialNameDropDown.Value;
            trialConfig = app.TrialConfig;
            trialConfig.CiceroLogOrigin = testCiceroLogOrigin;
            trialConfig.IsAutoAcquire = false;
            createFolder(trialConfig.ParentPath);
            app.BecExp = BecExp(trialName,config = trialConfig);
            app.setBecExpProperty;

            % Start exp
            app.BecExp.start
            app.TimeLabel.Text = "Time: " + string(app.BecExp.DateTime);
            app.SerialNumberLabel.Text = "Serial Number: " + string(app.BecExp.SerialNumber);

            % Move image and clg files in the background to simulate
            % experiments
            pauseTime = app.RunTimeIntervalEditField.Value;
            dataPath = app.BecExp.DataPath;
            parfeval(backgroundPool,@runLocalTest,0,testCiceroLogOrigin,samplePath,dataPath,pauseTime);
        end

        % Button pushed function: STOPButton_2
        function STOPButton_2Pushed(app, event)
            if ~app.IsStarted
                return
            end
            app.IsStarted = false;

            % Enable Od and Ad mix/animation when stop
            if app.PreviewerButtonGroup.SelectedObject == app.OpticalDensityButton
                app.BecExp.Od.Chart(1).IsEnabled = true;
                app.BecExp.Od.Chart(2).IsEnabled = true;
            else
                app.BecExp.Ad.Chart(1).IsEnabled = true;
                app.BecExp.Ad.Chart(2).IsEnabled = true;
            end

            % Stop and delete
            app.BecExp.stop;
            if isprop(app.BecExp,'CenterFit') % If Cloud center is saved, reset the list.
                if app.BecExp.CenterFit.IsSaveCenter
                    app.updateCloudCenterList;
                end
            end
            app.BecExp.delete;

            % Change buttons
            app.STARTButton_2.Enable = "on";
            app.STOPButton_2.Enable = "off";
            app.FASTSTOPButton_2.Enable = "off";
            app.PAUSEButton_2.Enable = "off";
            app.RESUMEButton_2.Enable = "off";
            app.UpdateButton_2.Enable = "off";
            app.HoldButton_2.Enable = "off";
            app.RefreshButton_2.Enable = "off";
            app.OpenDataPathButton_2.Enable = "off";
            app.DeletedRunParameterButton_2.Enable = "off";
            app.RunTimeIntervalEditField.Editable = "on";
            app.SelectTestDataPathButton.Enable = "on";
            app.TestDataPathEditField.Editable = "on";
            app.PlotMixandAnimationButton_2.Enable = "off";
            if app.IsMainComputer
                app.STARTButton.Enable = "on";
            end
            app.enableButton;
            app.resetButton;

            app.displayLog("Local test is stopped.")
        end

        % Button pushed function: FASTSTOPButton_2
        function FASTSTOPButton_2Pushed(app, event)
            if ~app.IsStarted
                return
            end
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
            answer = questdlg("Do you want to stop with out refreshing and saving" ...
                + "Od/Ad mix and animation?",'Fast Stop','Yes','No','No');
            delete(f);
            answer = string(answer);
            if answer == "Yes"
                app.IsStarted = false;

                % Stop and delete
                app.BecExp.fastStop;
                if isprop(app.BecExp,'CenterFit') % If Cloud center is saved, reset the list.
                    if app.BecExp.CenterFit.IsSaveCenter
                        app.updateCloudCenterList;
                    end
                end
                app.BecExp.delete;

                % Change buttons
                app.STARTButton_2.Enable = "on";
                app.STOPButton_2.Enable = "off";
                app.FASTSTOPButton_2.Enable = "off";
                app.PAUSEButton_2.Enable = "off";
                app.RESUMEButton_2.Enable = "off";
                app.UpdateButton_2.Enable = "off";
                app.HoldButton_2.Enable = "off";
                app.RefreshButton_2.Enable = "off";
                app.OpenDataPathButton_2.Enable = "off";
                app.DeletedRunParameterButton_2.Enable = "off";
                app.RunTimeIntervalEditField.Editable = "on";
                app.SelectTestDataPathButton.Enable = "on";
                app.TestDataPathEditField.Editable = "on";
                app.PlotMixandAnimationButton_2.Enable = "off";
                if app.IsMainComputer
                    app.STARTButton.Enable = "on";
                end
                app.enableButton;
                app.resetButton;

                app.displayLog("Local test is stopped.")
            end
        end

        % Button pushed function: PAUSEButton_2
        function PAUSEButton_2Pushed(app, event)
            if isvalid(app.BecExp)
                app.BecExp.pause;
                app.RESUMEButton_2.Enable = "on";
                app.PAUSEButton_2.Enable = "off";
            end
        end

        % Button pushed function: RESUMEButton_2
        function RESUMEButton_2Pushed(app, event)
            if isvalid(app.BecExp)
                app.BecExp.resume;
                app.PAUSEButton_2.Enable = "on";
                app.RESUMEButton_2.Enable = "off";
            end
        end

        % Button pushed function: UpdateButton_2
        function UpdateButton_2Pushed(app, event)
            if isvalid(app.BecExp)
                app.BecExp.update
            end
        end

        % Value changed function: HoldButton_2
        function HoldButton_2ValueChanged(app, event)
            value = app.HoldButton_2.Value;
            if isvalid(app.BecExp)
                app.BecExp.IsHoldRefresh = value;
            end
            if value
                app.RefreshButton_2.Enable = "off";
            else
                app.RefreshButton_2.Enable = "on";
            end
        end

        % Button pushed function: RefreshButton_2
        function RefreshButton_2Pushed(app, event)
            if isvalid(app.BecExp)
                app.BecExp.refresh
            end
        end

        % Button pushed function: OpenDataPathButton_2
        function OpenDataPathButton_2Pushed(app, event)
            if isvalid(app.BecExp)
                winopen(app.BecExp.DataPath);
            end
        end

        % Button pushed function: DeletedRunParameterButton_2
        function DeletedRunParameterButton_2Pushed(app, event)
            if isvalid(app.BecExp)
                app.displayLog("Deleted run parameters: " + num2str(app.BecExp.DeletedRunParameterList))
            end
        end

        % Button pushed function: OpenROIConfigButton
        function OpenROIConfigButtonPushed(app, event)
            winopen(which("roi.csv.xlsx"))
        end

        % Button pushed function: OpenParameterConfigButton
        function OpenParameterConfigButtonPushed(app, event)
            winopen(which("parameterUnit.csv.xlsx"))
        end

        % Button pushed function: OpenBECExperimentConfigButton
        function OpenBECExperimentConfigButtonPushed(app, event)
            winopen(which("becExpType.csv.xlsx"))
        end

        % Button pushed function: OpenCiceroOriginButton
        function OpenCiceroOriginButtonPushed(app, event)
            if isvalid(app.BecExp)
                winopen(app.BecExp.CiceroLogOrigin)
            elseif app.IsMainComputer
                winopen(app.BecExpConfig.CiceroLogOrigin(1))
            else
                winopen(app.BecExpLocalTestConfig.CiceroLogOrigin(1))
            end
        end

        % Button pushed function: OpenSetConfigButton
        function OpenSetConfigButtonPushed(app, event)
            open("setConfig.m")
        end

        % Button pushed function: RunSetConfigButton
        function RunSetConfigButtonPushed(app, event)
            setConfig;
            app.displayLog(" ")
            app.displayLog("Resetting [Config.mat].")
            app.startupFcn;
        end

        % Value changed function: DescriptionTextArea
        function DescriptionTextAreaValueChanged(app, event)
            value = string(app.DescriptionTextArea.Value);
            app.TrialConfig.Description = value;
            if isvalid(app.BecExp)
                app.BecExp.Description = value;
            end
        end

        % Value changed function: ScannedParameterDropDown
        function ScannedParameterDropDownValueChanged(app, event)
            value = app.ScannedParameterDropDown.Value;
            if ~ismember(value,app.ScannedParameterDropDown.Items)
                if isvalid(app.BecExp)
                    app.ScannedParameterDropDown.Value = app.BecExp.ScannedParameter(1);
                else
                    app.ScannedParameterDropDown.Value = ...
                        app.BecExpConfig(app.BecExpConfig.TrialName == app.TrialNameDropDown.Value,:).ScannedParameter(1);
                end
            end
            % app.TrialConfig.ScannedParameter = string(app.ScannedParameterDropDown.Value);
            % app.TrialConfig.ScannedParameterUnit = ...
            %     app.BecExpParameterUnit(app.BecExpParameterUnit.ScannedParameter ==...
            %     app.ScannedParameterDropDown.Value,:).ScannedParameterUnit;
            app.updateTrialConfigScannedParameterFromDropdowns
        end

        % Clicked callback: ScannedParameterDropDown
        function ScannedParameterDropDownClicked(app, event)
            item = event.InteractionInformation.Item;
            if isempty(item)
                return
            end
            % if isvalid(app.BecExp)
            %     app.BecExp.ScannedParameter = app.ScannedParameterDropDown.Value;
            %     app.BecExp.ScannedParameterUnit = ...
            %         app.BecExpParameterUnit(app.BecExpParameterUnit.ScannedParameter ==...
            %         app.ScannedParameterDropDown.Value,:).ScannedParameterUnit;
            %     app.BecExp.refresh;
            % end
            app.updateBecExpScannedParameterFromDropdowns
        end

        % Value changed function: AcquisitionDropDown
        function AcquisitionDropDownValueChanged(app, event)
            value = app.AcquisitionDropDown.Value;
            if ~ismember(value,app.AcquisitionDropDown.Items)
                app.AcquisitionDropDown.Value = ...
                    app.BecExpConfig(app.BecExpConfig.TrialName == app.TrialNameDropDown.Value,:).AcquisitionName;
            end
            app.TrialConfig.AcquisitionName = string(app.AcquisitionDropDown.Value);
        end

        % Value changed function: AutoAcquireImagesCheckBox
        function AutoAcquireImagesCheckBoxValueChanged(app, event)
            value = app.AutoAcquireImagesCheckBox.Value;
            app.TrialConfig.IsAutoAcquire = value;
        end

        % Value changed function: ROIDropDown
        function ROIDropDownValueChanged(app, event)
            value = app.ROIDropDown.Value;
            if ~ismember(value,app.ROIDropDown.Items)
                if isvalid(app.BecExp)
                    app.ROIDropDown.Value = app.BecExp.Roi.Name;
                else
                    app.ROIDropDown.Value = ...
                        app.BecExpConfig(app.BecExpConfig.TrialName == app.TrialNameDropDown.Value,:).RoiName;
                end
            end
            app.TrialConfig.RoiName = string(app.ROIDropDown.Value);
        end

        % Selection changed function: PreviewerButtonGroup
        function PreviewerButtonGroupSelectionChanged(app, event)
            selectedButton = app.PreviewerButtonGroup.SelectedObject;
            if isvalid(app.BecExp)
                if selectedButton == app.OpticalDensityButton
                    app.BecExp.Od.Gui(1).IsEnabled = true;
                    app.BecExp.Od.Gui(1).initialize(app.BecExp);
                    app.BecExp.Ad.toggle(false);
                else
                    app.BecExp.Ad.Gui(1).IsEnabled = true;
                    app.BecExp.Ad.Gui(1).initialize(app.BecExp);
                    app.BecExp.Od.toggle(false);
                end
                app.BecExp.Od.Gui(2).IsEnabled = app.ShowFringeRemovalDataCheckBox.Value;
            end
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            selectedNodes = app.Tree.SelectedNodes;
            name = selectedNodes.Text + "Tab";
            app.TabGroup2.SelectedTab = app.(name) ;
        end

        % Value changed function: CloudCenterReferenceDropDown
        function CloudCenterReferenceDropDownValueChanged(app, event)
            value = string(app.CloudCenterReferenceDropDown.Value);
            if value == "None"
                value = string(missing);
            end
            app.TrialConfig.CloudCenterReference = value;
            if isvalid(app.BecExp)
                if ~ismissing(app.TrialConfig.CloudCenterReference)
                    if ismember(app.TrialConfig.CloudCenterReference,app.CloudCenter.TrialName)
                        app.BecExp.CloudCenter = ...
                            app.CloudCenter(app.CloudCenter.TrialName == app.TrialConfig.CloudCenterReference,:).Center;
                        app.BecExp.refresh;
                    end
                end
            end
        end

        % Value changed function: ShowFringeRemovalDataCheckBox
        function ShowFringeRemovalDataCheckBoxValueChanged(app, event)
            value = app.ShowFringeRemovalDataCheckBox.Value;
            if isvalid(app.BecExp)
                app.BecExp.Od.Gui(2).IsEnabled = value;
                if value
                    app.BecExp.Od.doFringeRemoval
                elseif isvalid(app.BecExp.Od.Gui(2).App)
                    app.BecExp.Od.Gui(2).App.delete
                end
            end
        end

        % Value changed function: FringeRemovalMethodDropDown
        function FringeRemovalMethodDropDownValueChanged(app, event)
            value = string(app.FringeRemovalMethodDropDown.Value);
            if isvalid(app.BecExp)
                app.BecExp.Od.FringeRemovalMethod = value;
                app.BecExp.refresh;
            end
            app.TrialConfig.FringeRemovalMethod = value;
        end

        % Value changed function: ODMaxEditField
        function ODMaxEditFieldValueChanged(app, event)
            value = app.ODMaxEditField.Value;
            if value > app.ODMinEditField.Value
                if isvalid(app.BecExp)
                    app.BecExp.Od.CLim(2) = value;
                end
            else
                if isvalid(app.BecExp)
                    app.ODMaxEditField.Value = app.BecExp.Od.CLim(2);
                else
                    app.ODMaxEditField.Value = ...
                        app.BecExpConfig(app.BecExpConfig.TrialName == app.TrialNameDropDown.Value,:).OdCLim;
                end
                app.displayLog("OD CLim max value should be larger than min value.","warning")
            end
            app.TrialConfig.OdCLim = app.ODMaxEditField.Value;
        end

        % Value changed function: ODMinEditField
        function ODMinEditFieldValueChanged(app, event)
            value = app.ODMinEditField.Value;
            if value < app.ODMaxEditField.Value
                if isvalid(app.BecExp)
                    app.BecExp.Od.CLim(1) = value;
                end
            else
                if isvalid(app.BecExp)
                    app.ODMinEditField.Value = app.BecExp.Od.CLim(1);
                else
                    app.ODMinEditField.Value = 0;
                end
                app.displayLog("OD CLim max value should be larger than min value.","warning")
            end
        end

        % Value changed function: ImagingStageDropDown
        function ImagingStageDropDownValueChanged(app, event)
            value = string(app.ImagingStageDropDown.Value);
            app.TrialConfig.ImagingStage = value;
            if isvalid(app.BecExp)
                app.BecExp.Imaging.ImagingStage = value;
                app.BecExp.refresh("Imaging");
            end
        end

        % Value changed function: ImagingMethodDropDown
        function ImagingMethodDropDownValueChanged(app, event)
            value = string(app.ImagingMethodDropDown.Value);
            app.TrialConfig.ImagingMethod = value;
            if isvalid(app.BecExp)
                app.BecExp.Imaging.ImagingMethod = value;
                app.BecExp.refresh("Imaging");
            end
        end

        % Value changed function: ADMethodDropDown
        function ADMethodDropDownValueChanged(app, event)
            value = string(app.ADMethodDropDown.Value);
            app.TrialConfig.AdMethod = value;
            if isvalid(app.BecExp)
                app.BecExp.Ad.AdMethod = value;
                app.BecExp.refresh("Ad");
            end
        end

        % Value changed function: ADMaxEditField
        function ADMaxEditFieldValueChanged(app, event)
            value = app.ADMaxEditField.Value;
            if value > app.ADMinEditField.Value
                if isvalid(app.BecExp)
                    app.BecExp.Ad.CLim(2) = value;
                end
            else
                if isvalid(app.BecExp)
                    app.ADMaxEditField.Value = app.BecExp.Ad.CLim(2);
                else
                    app.ADMaxEditField.Value = ...
                        app.BecExpConfig(app.BecExpConfig.TrialName == app.TrialNameDropDown.Value,:).AdCLim;
                end
                app.displayLog("AD CLim max value should be larger than min value.","warning")
            end
            app.TrialConfig.AdCLim = app.ADMaxEditField.Value;
        end

        % Value changed function: ADMinEditField
        function ADMinEditFieldValueChanged(app, event)
            value = app.ADMinEditField.Value;
            if value < app.ADMaxEditField.Value
                if isvalid(app.BecExp)
                    app.BecExp.Ad.CLim(1) = value;
                end
            else
                if isvalid(app.BecExp)
                    app.ADMinEditField.Value = app.BecExp.Ad.CLim(1);
                else
                    app.ADMinEditField.Value = 0;
                end
                app.displayLog("AD CLim max value should be larger than min value.","warning")
            end
        end

        % Value changed function: DensityFitMethodDropDown
        function DensityFitMethodDropDownValueChanged(app, event)
            value = string(app.DensityFitMethodDropDown.Value);
            if ~isempty(app.BecExp)
                if isvalid(app.BecExp) && isprop(app.BecExp,'DensityFit')
                    app.BecExp.DensityFit.FitMethod = value;
                    app.BecExp.refresh("DensityFit");
                end
            end
            app.TrialConfig.DensityFitMethod = value;
        end

        % Value changed function: AtomNumberMaxEditField
        function AtomNumberMaxEditFieldValueChanged(app, event)
            value = app.AtomNumberMaxEditField.Value;
            if ~isempty(app.BecExp)
                if isvalid(app.BecExp) && isprop(app.BecExp,'AtomNumber')
                    app.BecExp.AtomNumber.YLim = [0,value];
                end
            end
            app.TrialConfig.AtomNumberYLim = value;
        end

        % Value changed function: ShowRawCheckBox
        function ShowRawCheckBoxValueChanged(app, event)
            value = app.ShowRawCheckBox.Value;
            if isvalid(app.BecExp)
                if isprop(app.BecExp,'AtomNumber')
                    app.BecExp.AtomNumber.IsShowRaw = value;
                end
            end
        end

        % Value changed function: ShowThermalCheckBox
        function ShowThermalCheckBoxValueChanged(app, event)
            value = app.ShowThermalCheckBox.Value;
            if isvalid(app.BecExp)
                if isprop(app.BecExp,'AtomNumber')
                    app.BecExp.AtomNumber.IsShowThermal = value;
                end
            end
        end

        % Value changed function: ShowNormalizedAtomNumberCheckBox
        function ShowNormalizedAtomNumberCheckBoxValueChanged(app, event)
            value = app.ShowNormalizedAtomNumberCheckBox.Value;
            if isvalid(app.BecExp)
                if isprop(app.BecExp,'AtomNumber')
                    app.BecExp.AtomNumber.IsShowNormalized = value;
                    app.BecExp.refresh("AtomNumber")
                end
            end
        end

        % Value changed function: CenterFitMethodDropDown
        function CenterFitMethodDropDownValueChanged(app, event)
            value = string(app.CenterFitMethodDropDown.Value);
            if ~isempty(app.BecExp)
                if isvalid(app.BecExp) && isprop(app.BecExp,'CenterFit')
                    app.BecExp.CenterFit.FitMethod = value;
                    app.BecExp.refresh("CenterFit");
                end
            end
            app.TrialConfig.CenterFitMethod = value;
        end

        % Value changed function: SaveCloudCenterCheckBox
        function SaveCloudCenterCheckBoxValueChanged(app, event)
            value = app.SaveCloudCenterCheckBox.Value;
            if ~isempty(app.BecExp)
                if isvalid(app.BecExp) && isprop(app.BecExp,'CenterFit')
                    app.BecExp.CenterFit.IsSaveCenter = value;
                end
            end
        end

        % Value changed function: SerialCheckBox
        function SerialCheckBoxValueChanged(app, event)
            value = app.SerialCheckBox.Value;
            if value
                app.SerialEditField.Enable = "on";
                app.SerialEditField2.Enable = "on";
            else
                app.SerialEditField.Enable = "off";
                app.SerialEditField2.Enable = "off";
            end
        end

        % Button pushed function: SearchButton
        function SearchButtonPushed(app, event)

            app.LoadButton.Enable = "off";
            app.OpenDataPathButton_3.Enable = "off";
            if app.ServerButtonGroup.SelectedObject == app.RemoteButton
                databaseName = app.BecExpConfig.DatabaseName(1);
                databaseTableName = app.BecExpConfig.DatabaseTableName(1);
            else
                databaseName = app.BecExpLocalTestConfig.DatabaseName(1);
                databaseTableName = app.BecExpLocalTestConfig.DatabaseTableName(1);
            end
            startTime = "'" + string(app.StartDateDatePicker.Value) + "'";
            endTime = "'" + string(app.EndDateDatePicker.Value) + " 23:59:59'";
            conn = createReader(databaseName);
            query = "SELECT ""SerialNumber"", ""DateTime"", ""Name""," + ...
                " ""ScannedParameter"", ""Description"", ""DataPath"" FROM "...
                + databaseTableName +" WHERE ""DateTime"" >= " + startTime + ...
                " AND ""DateTime"" <= " + endTime;
            if app.TrialNameDropDown_2.Value ~= "All"
                query = query + " AND ""Name"" = '" + ...
                    app.TrialNameDropDown_2.Value + "'";
            end
            if app.SerialCheckBox.Value
                query = query + " AND ""SerialNumber"" >= " +...
                    string(app.SerialEditField.Value) + ...
                    " AND ""SerialNumber"" <= " + string(app.SerialEditField2.Value);
            end
            if ~isempty(app.KeyWordsEditField.Value)
                keyWords = strtrim(split(app.KeyWordsEditField.Value,","));
                keyWords = "'%" + keyWords + "%'";
                keyWords = strjoin(keyWords,", ");
                keyWords = "(array[" + keyWords + "])";
                query = query + " AND ""Description"" ILIKE ALL" + keyWords;
            end
            query = query + " ORDER BY ""SerialNumber"" DESC LIMIT 2000";
            data = pgFetch(conn,query);
            app.DataTable.Data = data;
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            app.UnloadButton.Enable = "on";
            app.STARTButton.Enable = "off";
            app.STARTButton_2.Enable = "off";
            app.DataTable.Enable = "inactive";
            app.SearchButton.Enable = "off";
            app.HoldButton_3.Enable = "on";
            app.RefreshButton_3.Enable = "on";
            app.OverwriteTrialTypeButton.Enable = "off";
            app.SaveAsNewTrialTypeButton.Enable = "off";
            app.disableButton;
            if app.ServerButtonGroup.SelectedObject == app.RemoteButton
                databaseName = app.BecExpConfig.DatabaseName(1);
                databaseTableName = app.BecExpConfig.DatabaseTableName(1);
            else
                databaseName = app.BecExpLocalTestConfig.DatabaseName(1);
                databaseTableName = app.BecExpLocalTestConfig.DatabaseTableName(1);
            end
            conn = createReader(databaseName);

            app.BecExp = loadTrial(conn,databaseTableName,app.DataTable.Data.SerialNumber(app.DataTable.Selection));
            app.displayBecExpProperty;
            app.BecExp.refresh;
            app.BecExp.show;
        end

        % Button pushed function: UnloadButton
        function UnloadButtonPushed(app, event)
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
            answer = questdlg("Do you want to save changes for Trial #"+ string(app.BecExp.SerialNumber) +"?");
            delete(f)
            if string(answer) == "Yes"
                for ii = 1:numel(app.BecExp.AnalysisMethod)
                    app.BecExp.(app.BecExp.AnalysisMethod(ii)).refresh;
                    app.BecExp.(app.BecExp.AnalysisMethod(ii)).save;
                    app.BecExp.(app.BecExp.AnalysisMethod(ii)).close;
                end
                app.BecExp.update;
            elseif string(answer) == "No"
                for ii = 1:numel(app.BecExp.AnalysisMethod)
                    app.BecExp.(app.BecExp.AnalysisMethod(ii)).close;
                end
            else
                return
            end

            % Change buttons
            app.UnloadButton.Enable = "off";
            app.DataTable.Enable = "on";
            if app.IsMainComputer
                app.STARTButton.Enable = "on";
            end
            app.STARTButton_2.Enable = "on";
            app.SearchButton.Enable = "on";
            app.OpenDataPathButton_3.Enable = "off";
            app.HoldButton_3.Enable = "off";
            app.RefreshButton_3.Enable = "off";
            app.OverwriteTrialTypeButton.Enable = "on";
            app.SaveAsNewTrialTypeButton.Enable = "on";
            app.enableButton;
            app.resetButton;

            % Delete
            app.BecExp.delete;

            % Change the config back to the default
            app.TrialNameDropDown.Value = "Test";
            app.displayConfigProperty;
        end

        % Button pushed function: OpenDataPathButton_3
        function OpenDataPathButton_3Pushed(app, event)
            winopen(app.DataTable.Data.DataPath(app.DataTable.Selection));
        end

        % Value changed function: HoldButton_3
        function HoldButton_3ValueChanged(app, event)
            value = app.HoldButton_3.Value;
            if isvalid(app.BecExp)
                app.BecExp.IsHoldRefresh = value;
            end
            if value
                app.RefreshButton_3.Enable = "off";
            else
                app.RefreshButton_3.Enable = "on";
            end
        end

        % Button pushed function: RefreshButton_3
        function RefreshButton_3Pushed(app, event)
            if isvalid(app.BecExp)
                app.BecExp.refresh
            end
        end

        % Clicked callback: DataTable
        function DataTableClicked(app, event)
            displayRow = event.InteractionInformation.DisplayRow;
            % displayColumn = event.InteractionInformation.DisplayColumn;
            if isempty(displayRow)
                app.LoadButton.Enable = "off";
                app.OpenDataPathButton_3.Enable = "off";
            else
                app.OpenDataPathButton_3.Enable = "on";
                if app.STOPButton_2.Enable == "off" && app.STOPButton.Enable == "off" && app.UnloadButton.Enable == "off"
                    app.LoadButton.Enable = "on";
                end
            end
        end

        % Close request function: BECExperimentControlPanelUIFigure
        function BECExperimentControlPanelUIFigureCloseRequest(app, event)
            if app.STOPButton_2.Enable == "on"
                f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
                answer = questdlg("A BecExp is still running. Do you want to close the program? " + ...
                    "Will stop the BecExp before closing the program.","",'Yes','No','Force Close','No');
                delete(f)
                if string(answer) == "Yes"
                    app.BecExp.stop;
                    app.BecExp.delete;
                    app.displayLog("Local test is stopped.")
                    delete(app)
                elseif string(answer) == "Force Close"
                    try
                        app.BecExp.Acquisition.stopCamera;
                    catch
                    end
                    delete(app)
                end
            elseif app.STOPButton.Enable == "on"
                f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
                answer = questdlg("A BecExp is still running. Do you want to close the program? " + ...
                    "Will stop the BecExp before closing the program.","",'Yes','No','Force Close','No');
                delete(f)
                if string(answer) == "Yes"
                    app.BecExp.stop;
                    app.BecExp.delete;
                    app.displayLog("Onsite experiment is stopped.")
                    delete(app)
                elseif string(answer) == "Force Close"
                    delete(app)
                end
            elseif app.UnloadButton.Enable == "on"
                f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
                answer = questdlg("A BecExp is still loaded. " + ...
                    "Do you want to save the changes before closing the program? ",...
                    "",'Yes','No','Force Close','No');
                delete(f)
                if string(answer) == "Yes"
                    for ii = 1:numel(app.BecExp.AnalysisMethod)
                        app.BecExp.(app.BecExp.AnalysisMethod(ii)).refresh;
                        app.BecExp.(app.BecExp.AnalysisMethod(ii)).save;
                        app.BecExp.(app.BecExp.AnalysisMethod(ii)).close;
                    end
                    delete(app)
                elseif string(answer) == "No"
                    for ii = 1:numel(app.BecExp.AnalysisMethod)
                        app.BecExp.(app.BecExp.AnalysisMethod(ii)).close;
                    end
                    delete(app)
                elseif string(answer) == "Force Close"
                    delete(app)
                end
            else
                delete(app)
            end

        end

        % Value changed function: TrialNameDropDown_2
        function TrialNameDropDown_2ValueChanged(app, event)
            value = app.TrialNameDropDown_2.Value;
            if ~ismember(value,app.TrialNameDropDown_2.Items)
                app.TrialNameDropDown_2.Value = "All";
            end
        end

        % Button pushed function: InitializeButton
        function InitializeButtonPushed(app, event)
            if isvalid(app.BecExp)
                selectedAnalysis = app.Tree.SelectedNodes;
                if ~isempty(selectedAnalysis)
                    selectedAnalysisName = string(selectedAnalysis.Text);
                    app.BecExp.(selectedAnalysisName).initialize
                end
            end
        end

        % Button pushed function: RefreshButton_4
        function RefreshButton_4Pushed(app, event)
            if isvalid(app.BecExp)
                selectedAnalysis = app.Tree.SelectedNodes;
                if ~isempty(selectedAnalysis)
                    selectedAnalysisName = string(selectedAnalysis.Text);
                    app.BecExp.(selectedAnalysisName).refresh
                end
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            if isvalid(app.BecExp)
                selectedAnalysis = app.Tree.SelectedNodes;
                if ~isempty(selectedAnalysis)
                    selectedAnalysisName = string(selectedAnalysis.Text);
                    app.BecExp.(selectedAnalysisName).save
                end
            end
        end

        % Button pushed function: ShowButton
        function ShowButtonPushed(app, event)
            if isvalid(app.BecExp)
                selectedAnalysis = app.Tree.SelectedNodes;
                if ~isempty(selectedAnalysis)
                    selectedAnalysisName = string(selectedAnalysis.Text);
                    app.BecExp.(selectedAnalysisName).show
                end
            end
        end

        % Button pushed function: AssignBecExptoWorkSpaceButton
        function AssignBecExptoWorkSpaceButtonPushed(app, event)
            if isvalid(app.BecExp)
                f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
                varName = inputdlg("Please enter the name of the variable you want to assign BecExp as.",...
                    "Assign BecExp to the base work-space",1,"becExp");
                delete(f);
                if ~isempty(varName)
                    assignin('base',varName{1},app.BecExp);
                end
            end
        end

        % Button pushed function: ResetImageAcquisitionButton
        function ResetImageAcquisitionButtonPushed(app, event)
            if app.IsMainComputer
                imaqreset;
            end
        end

        % Button pushed function: CreateParallelPoolButton
        function CreateParallelPoolButtonPushed(app, event)
            parpool;
        end

        % Value changed function: AveragingMethodDropDown
        function AveragingMethodDropDownValueChanged(app, event)
            value = app.AveragingMethodDropDown.Value;
            if ~ismember(value,app.AveragingMethodDropDown.Items)
                if isvalid(app.BecExp)
                    app.AveragingMethodDropDown.Value = app.BecExp.AveragingMethod;
                else
                    app.AveragingMethod.Value = ...
                        app.BecExpConfig(app.BecExpConfig.TrialName == app.TrialNameDropDown.Value,:).AveragingMethod;
                end
            end
            app.TrialConfig.AveragingMethod = string(app.AveragingMethodDropDown.Value);
            
        end

        % Clicked callback: AveragingMethodDropDown
        function AveragingMethodDropDownClicked(app, event)
            item = event.InteractionInformation.Item;
            if isempty(item)
                return
            end
            if isvalid(app.BecExp)
                app.BecExp.AveragingMethod = app.AveragingMethodDropDown.Value;
                app.BecExp.refreshFigure;
            end
        end

        % Value changed function: ScopeValueNameTextArea
        function ScopeValueNameTextAreaValueChanged(app, event)
            value = string(app.ScopeValueNameTextArea.Value);
            if ~isempty(app.BecExp)
                if isvalid(app.BecExp) && isprop(app.BecExp,'ScopeValue')
                    app.BecExp.ScopeValue.FullValueName = strsplit(value,";");
                    app.BecExp.refresh("ScopeValue");
                end
            end
            app.TrialConfig.ScopeValueName = value;
        end

        % Button pushed function: EditHardwareAssociationButton
        function EditHardwareAssociationButtonPushed(app, event)
            HardwareAssociation(app);
        end

        % Value changed function: AtomNumberFitMethodDropDown
        function AtomNumberFitMethodDropDownValueChanged(app, event)
            value = app.AtomNumberFitMethodDropDown.Value;
            if ~isempty(app.BecExp)
                if isvalid(app.BecExp) && isprop(app.BecExp,'AtomNumber')
                    app.BecExp.AtomNumber.FitMethod = value;
                    app.BecExp.refresh("AtomNumber");
                end
            end
            app.TrialConfig.AtomNumberFitMethod = value;
        end

        % Button pushed function: PlotMixandAnimationButton
        function PlotMixandAnimationButtonPushed(app, event)
            if ~isempty(app.BecExp)
                if isvalid(app.BecExp)
                    selectedButton = app.PreviewerButtonGroup.SelectedObject;
                    if selectedButton == app.OpticalDensityButton
                        app.BecExp.Od.Chart(1).IsEnabled = true;
                        app.BecExp.Od.Chart(2).IsEnabled = true;
                        app.BecExp.refresh("Od")
                        app.BecExp.Od.Chart(2).showGif
                        app.BecExp.Od.Chart(1).IsEnabled = false;
                        app.BecExp.Od.Chart(2).IsEnabled = false;
                    else
                        app.BecExp.Ad.Chart(1).IsEnabled = true;
                        app.BecExp.Ad.Chart(2).IsEnabled = true;
                        app.BecExp.refresh("Ad")
                        app.BecExp.Ad.Chart(2).showGif
                        app.BecExp.Ad.Chart(1).IsEnabled = false;
                        app.BecExp.Ad.Chart(2).IsEnabled = false;
                    end
                end
            end
        end

        % Button pushed function: PlotMixandAnimationButton_2
        function PlotMixandAnimationButton_2Pushed(app, event)
            if ~isempty(app.BecExp)
                if isvalid(app.BecExp)
                    selectedButton = app.PreviewerButtonGroup.SelectedObject;
                    if selectedButton == app.OpticalDensityButton
                        app.BecExp.Od.Chart(1).IsEnabled = true;
                        app.BecExp.Od.Chart(2).IsEnabled = true;
                        app.BecExp.refresh("Od")
                        app.BecExp.Od.Chart(2).showGif
                        app.BecExp.Od.Chart(1).IsEnabled = false;
                        app.BecExp.Od.Chart(2).IsEnabled = false;
                    else
                        app.BecExp.Ad.Chart(1).IsEnabled = true;
                        app.BecExp.Ad.Chart(2).IsEnabled = true;
                        app.BecExp.refresh("Ad")
                        app.BecExp.Ad.Chart(2).showGif
                        app.BecExp.Ad.Chart(1).IsEnabled = false;
                        app.BecExp.Ad.Chart(2).IsEnabled = false;
                    end
                end
            end
        end

        % Button pushed function: ModifyXThermalFitButton
        function ModifyXThermalFitButtonPushed(app, event)
            if ~isempty(app.BecExp)
                if isvalid(app.BecExp) && isprop(app.BecExp,'CenterFit')
                    FitOverride(app.BecExp.CenterFit.FitDataThermal(1),app)
                end
            end
        end

        % Button pushed function: ModifyYThermalFitButton
        function ModifyYThermalFitButtonPushed(app, event)
            if ~isempty(app.BecExp)
                if isvalid(app.BecExp) && isprop(app.BecExp,'CenterFit')
                    FitOverride(app.BecExp.CenterFit.FitDataThermal(2),app)
                end
            end
        end

        % Button pushed function: PlotHistogramofScannedParameterButton
        function PlotHistogramofScannedParameterButtonPushed(app, event)
            %Steps: Check if becExp object exists, grab scanned
            %parameterlist, counts instances of each unique item in
            %parameterlist,
            %and then plot the histogram as a figure.
            if ~isempty(app.BecExp)
                if isvalid(app.BecExp)
                    data=app.BecExp.ScannedParameterList;
                    uniquedata=unique(data,'sorted');
                    uniquedatacounts=zeros(size(uniquedata));
                    for ii=1:length(uniquedata)
                        uniquedatacounts(ii)=sum(data==uniquedata(ii));
                    end
                    fcounts=figure();
                    axcounts=axes(fcounts);
                    bar(axcounts, uniquedata, uniquedatacounts);
                    xlabel(axcounts,app.BecExp.ScannedParameter);
                    ylabel(axcounts,'Counts of Shots');
                    title(axcounts, 'Histogram of Scanned Parameter');
                    
                end
            end

        end

        % Value changed function: ScannedParameter2DropDown
        function ScannedParameter2DropDownValueChanged(app, event)
            value = app.ScannedParameter2DropDown.Value;
            if ~ismember(value, app.ScannedParameter2DropDown.Items)
                if isvalid(app.BecExp)
                    if app.BecExp.Is2dScan
                        app.ScannedParameter2DropDown.Value = app.BecExp.ScannedParameter(1);
                    else
                        app.ScannedParameter2DropDown.Value = "None";
                    end
                else
                    configVal = app.BecExpConfig(app.BecExpConfig.TrialName == app.TrialNameDropDown.Value,:).ScannedParameter;
                    configVal = str2strmat(configVal);
                    if size(configVal,2) == 2
                        app.ScannedParameter2DropDown.Value = ...
                            aconfigVal(2);
                    else
                        app.ScannedParameter2DropDown.Value = "None";
                    end
                end
            end
            % Update the ScannedParameter based on both dropdowns
            app.updateTrialConfigScannedParameterFromDropdowns;   
        end

        % Clicked callback: ScannedParameter2DropDown
        function ScannedParameter2DropDownClicked(app, event)
            item = event.InteractionInformation.Item;
            if isempty(item)
                return
            end
            % Update the ScannedParameter based on both dropdowns
            app.updateBecExpScannedParameterFromDropdowns;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BECExperimentControlPanelUIFigure = uifigure('Visible', 'off');
            app.BECExperimentControlPanelUIFigure.Name = 'BEC Experiment Control Panel';
            app.BECExperimentControlPanelUIFigure.CloseRequestFcn = createCallbackFcn(app, @BECExperimentControlPanelUIFigureCloseRequest, true);
            app.BECExperimentControlPanelUIFigure.Tag = 'BecControl';
            app.GridLayout = uigridlayout(app.BECExperimentControlPanelUIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.BECExperimentPropertiesPanel = uipanel(app.GridLayout2);
            app.BECExperimentPropertiesPanel.Title = 'BEC Experiment Properties';
            app.TimeLabel = uilabel(app.BECExperimentPropertiesPanel);
            app.TimeLabel.Text = 'Time:';
            app.PreviewerButtonGroup = uibuttongroup(app.BECExperimentPropertiesPanel);
            app.PreviewerButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PreviewerButtonGroupSelectionChanged, true);
            app.PreviewerButtonGroup.Title = 'Previewer';
            app.OpticalDensityButton = uitogglebutton(app.PreviewerButtonGroup);
            app.OpticalDensityButton.Text = 'Optical Density';
            app.OpticalDensityButton.Value = true;
            app.AtomicDensityButton = uitogglebutton(app.PreviewerButtonGroup);
            app.AtomicDensityButton.Text = 'Atomic Density';
            app.AcquisitionLabel = uilabel(app.BECExperimentPropertiesPanel);
            app.AcquisitionLabel.Text = 'Acquisition:';
            app.AcquisitionDropDown = uidropdown(app.BECExperimentPropertiesPanel);
            app.AcquisitionDropDown.Editable = 'on';
            app.AcquisitionDropDown.ValueChangedFcn = createCallbackFcn(app, @AcquisitionDropDownValueChanged, true);
            app.ROILabel = uilabel(app.BECExperimentPropertiesPanel);
            app.ROILabel.Text = 'ROI:';
            app.ROIDropDown = uidropdown(app.BECExperimentPropertiesPanel);
            app.ROIDropDown.Editable = 'on';
            app.ROIDropDown.ValueChangedFcn = createCallbackFcn(app, @ROIDropDownValueChanged, true);
            app.AutoAcquireImagesCheckBox = uicheckbox(app.BECExperimentPropertiesPanel);
            app.AutoAcquireImagesCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutoAcquireImagesCheckBoxValueChanged, true);
            app.AutoAcquireImagesCheckBox.Text = 'Auto Acquire Images';
            app.SerialNumberLabel = uilabel(app.BECExperimentPropertiesPanel);
            app.SerialNumberLabel.Text = 'Serial Number:';
            app.TrialNameLabel = uilabel(app.BECExperimentPropertiesPanel);
            app.TrialNameLabel.Text = 'Trial Name:';
            app.TrialNameDropDown = uidropdown(app.BECExperimentPropertiesPanel);
            app.TrialNameDropDown.Editable = 'on';
            app.TrialNameDropDown.ValueChangedFcn = createCallbackFcn(app, @TrialNameDropDownValueChanged, true);
            app.ScannedParameterLabel = uilabel(app.BECExperimentPropertiesPanel);
            app.ScannedParameterLabel.Text = 'Scanned Parameter:';
            app.ScannedParameterDropDown = uidropdown(app.BECExperimentPropertiesPanel);
            app.ScannedParameterDropDown.Editable = 'on';
            app.ScannedParameterDropDown.ValueChangedFcn = createCallbackFcn(app, @ScannedParameterDropDownValueChanged, true);
            app.ScannedParameterDropDown.ClickedFcn = createCallbackFcn(app, @ScannedParameterDropDownClicked, true);
            app.OverwriteTrialTypeButton = uibutton(app.BECExperimentPropertiesPanel, 'push');
            app.OverwriteTrialTypeButton.ButtonPushedFcn = createCallbackFcn(app, @OverwriteTrialTypeButtonPushed, true);
            app.OverwriteTrialTypeButton.Text = 'Overwrite Trial Type';
            app.AtomLabel = uilabel(app.BECExperimentPropertiesPanel);
            app.AtomLabel.Text = 'Atom:';
            app.SaveAsNewTrialTypeButton = uibutton(app.BECExperimentPropertiesPanel, 'push');
            app.SaveAsNewTrialTypeButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAsNewTrialTypeButtonPushed, true);
            app.SaveAsNewTrialTypeButton.Text = 'Save As New Trial Type';
            app.AnalysisMethodsPanel = uipanel(app.BECExperimentPropertiesPanel);
            app.AnalysisMethodsPanel.Title = 'Analysis Methods';
            app.InitializeButton = uibutton(app.AnalysisMethodsPanel, 'push');
            app.InitializeButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeButtonPushed, true);
            app.InitializeButton.Text = 'Initialize';
            app.RefreshButton_4 = uibutton(app.AnalysisMethodsPanel, 'push');
            app.RefreshButton_4.ButtonPushedFcn = createCallbackFcn(app, @RefreshButton_4Pushed, true);
            app.RefreshButton_4.Text = 'Refresh';
            app.ShowButton = uibutton(app.AnalysisMethodsPanel, 'push');
            app.ShowButton.ButtonPushedFcn = createCallbackFcn(app, @ShowButtonPushed, true);
            app.ShowButton.Text = 'Show';
            app.SaveButton = uibutton(app.AnalysisMethodsPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.Tree = uitree(app.AnalysisMethodsPanel, 'checkbox');
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.OdNode = uitreenode(app.Tree);
            app.OdNode.Text = 'Od';
            app.ImagingNode = uitreenode(app.Tree);
            app.ImagingNode.Text = 'Imaging';
            app.AdNode = uitreenode(app.Tree);
            app.AdNode.Text = 'Ad';
            app.DensityFitNode = uitreenode(app.Tree);
            app.DensityFitNode.Text = 'DensityFit';
            app.AtomNumberNode = uitreenode(app.Tree);
            app.AtomNumberNode.Text = 'AtomNumber';
            app.TofNode = uitreenode(app.Tree);
            app.TofNode.Text = 'Tof';
            app.CenterFitNode = uitreenode(app.Tree);
            app.CenterFitNode.Text = 'CenterFit';
            app.KapitzaDiracNode = uitreenode(app.Tree);
            app.KapitzaDiracNode.Text = 'KapitzaDirac';
            app.ScopeValueNode = uitreenode(app.Tree);
            app.ScopeValueNode.Text = 'ScopeValue';
            % Assign Checked Nodes
            app.Tree.CheckedNodesChangedFcn = createCallbackFcn(app, @TreeCheckedNodesChanged, true);
            app.TabGroup2 = uitabgroup(app.BECExperimentPropertiesPanel);
            app.OdTab = uitab(app.TabGroup2);
            app.OdTab.Title = 'Od';
            app.ShowFringeRemovalDataCheckBox = uicheckbox(app.OdTab);
            app.ShowFringeRemovalDataCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowFringeRemovalDataCheckBoxValueChanged, true);
            app.ShowFringeRemovalDataCheckBox.Text = 'Show Fringe Removal Data';
            app.FringeRemovalMethodLabel = uilabel(app.OdTab);
            app.FringeRemovalMethodLabel.Text = 'Fringe Removal Method:';
            app.FringeRemovalMethodDropDown = uidropdown(app.OdTab);
            app.FringeRemovalMethodDropDown.Items = {'None', 'LSR'};
            app.FringeRemovalMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @FringeRemovalMethodDropDownValueChanged, true);
            app.FringeRemovalMethodDropDown.Value = 'None';
            app.ODMinLabel = uilabel(app.OdTab);
            app.ODMinLabel.Text = 'OD Max:';
            app.ODMaxEditField = uieditfield(app.OdTab, 'numeric');
            app.ODMaxEditField.ValueChangedFcn = createCallbackFcn(app, @ODMaxEditFieldValueChanged, true);
            app.ODMaxEditField.Value = 4;
            app.ODMaxLabel = uilabel(app.OdTab);
            app.ODMaxLabel.Text = 'OD Min:';
            app.ODMinEditField = uieditfield(app.OdTab, 'numeric');
            app.ODMinEditField.ValueChangedFcn = createCallbackFcn(app, @ODMinEditFieldValueChanged, true);
            app.ImagingTab = uitab(app.TabGroup2);
            app.ImagingTab.Title = 'Imaging';
            app.ImagingStageLabel = uilabel(app.ImagingTab);
            app.ImagingStageLabel.Text = 'Imaging Stage:';
            app.ImagingStageDropDown = uidropdown(app.ImagingTab);
            app.ImagingStageDropDown.Items = {'LF', 'HF', 'NI'};
            app.ImagingStageDropDown.ValueChangedFcn = createCallbackFcn(app, @ImagingStageDropDownValueChanged, true);
            app.ImagingStageDropDown.Value = 'LF';
            app.ImagingMethodDropDownLabel = uilabel(app.ImagingTab);
            app.ImagingMethodDropDownLabel.Text = 'Imaging Method:';
            app.ImagingMethodDropDown = uidropdown(app.ImagingTab);
            app.ImagingMethodDropDown.Items = {'Absorption', 'Dispersive', 'Flourescence'};
            app.ImagingMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @ImagingMethodDropDownValueChanged, true);
            app.ImagingMethodDropDown.Value = 'Absorption';
            app.AdTab = uitab(app.TabGroup2);
            app.AdTab.Title = 'Ad';
            app.ADMethodLabel = uilabel(app.AdTab);
            app.ADMethodLabel.Text = 'AD Method:';
            app.ADMethodDropDown = uidropdown(app.AdTab);
            app.ADMethodDropDown.Items = {'TwoLevelWeakLight', 'RandomPolarization', 'UniformStrongLight', 'StrongLight', 'PhaseContrastImaging'};
            app.ADMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @ADMethodDropDownValueChanged, true);
            app.ADMethodDropDown.Value = 'TwoLevelWeakLight';
            app.ADMinLabel = uilabel(app.AdTab);
            app.ADMinLabel.Text = 'AD Max:';
            app.ADMaxEditField = uieditfield(app.AdTab, 'numeric');
            app.ADMaxEditField.ValueChangedFcn = createCallbackFcn(app, @ADMaxEditFieldValueChanged, true);
            app.ADMaxEditField.Value = 8;
            app.ADMaxLabel = uilabel(app.AdTab);
            app.ADMaxLabel.Text = 'AD Min:';
            app.ADMinEditField = uieditfield(app.AdTab, 'numeric');
            app.ADMinEditField.ValueChangedFcn = createCallbackFcn(app, @ADMinEditFieldValueChanged, true);
            app.times1013m2Label = uilabel(app.AdTab);
            app.times1013m2Label.Interpreter = 'tex';
            app.times1013m2Label.Text = '\times 10^{13} m^{-2}';
            app.times1013m2Label_2 = uilabel(app.AdTab);
            app.times1013m2Label_2.Interpreter = 'tex';
            app.times1013m2Label_2.Text = '\times 10^{13} m^{-2}';
            app.DensityFitTab = uitab(app.TabGroup2);
            app.DensityFitTab.Title = 'DensityFit';
            app.DensityFitMethodLabel = uilabel(app.DensityFitTab);
            app.DensityFitMethodLabel.Text = 'Density Fit Method:';
            app.DensityFitMethodDropDown = uidropdown(app.DensityFitTab);
            app.DensityFitMethodDropDown.Items = {'GaussianFit1D', 'BosonicGaussianFit1D'};
            app.DensityFitMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @DensityFitMethodDropDownValueChanged, true);
            app.DensityFitMethodDropDown.Value = 'GaussianFit1D';
            app.AtomNumberTab = uitab(app.TabGroup2);
            app.AtomNumberTab.Title = 'AtomNumber';
            app.AtomNumberMaxLabel = uilabel(app.AtomNumberTab);
            app.AtomNumberMaxLabel.Text = 'Atom Number  Max:';
            app.AtomNumberMaxEditField = uieditfield(app.AtomNumberTab, 'numeric');
            app.AtomNumberMaxEditField.Limits = [0 Inf];
            app.AtomNumberMaxEditField.ValueChangedFcn = createCallbackFcn(app, @AtomNumberMaxEditFieldValueChanged, true);
            app.AtomNumberMaxEditField.Value = 30;
            app.times106Label = uilabel(app.AtomNumberTab);
            app.times106Label.Interpreter = 'tex';
            app.times106Label.Text = '\times 10^6';
            app.ShowRawCheckBox = uicheckbox(app.AtomNumberTab);
            app.ShowRawCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowRawCheckBoxValueChanged, true);
            app.ShowRawCheckBox.Text = 'Show Raw';
            app.ShowRawCheckBox.Value = true;
            app.ShowThermalCheckBox = uicheckbox(app.AtomNumberTab);
            app.ShowThermalCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowThermalCheckBoxValueChanged, true);
            app.ShowThermalCheckBox.Text = 'Show Thermal';
            app.ShowThermalCheckBox.Value = true;
            app.ShowNormalizedAtomNumberCheckBox = uicheckbox(app.AtomNumberTab);
            app.ShowNormalizedAtomNumberCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowNormalizedAtomNumberCheckBoxValueChanged, true);
            app.ShowNormalizedAtomNumberCheckBox.Text = 'Show Normalized Atom Number';
            app.ShowNormalizedAtomNumberCheckBox.Value = true;
            app.AtomNumberFitMethodDropDownLabel = uilabel(app.AtomNumberTab);
            app.AtomNumberFitMethodDropDownLabel.Text = 'Atom Number Fit Method:';
            app.AtomNumberFitMethodDropDown = uidropdown(app.AtomNumberTab);
            app.AtomNumberFitMethodDropDown.Items = {'None', 'GaussianFit1D', 'ParabolicFit1D'};
            app.AtomNumberFitMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @AtomNumberFitMethodDropDownValueChanged, true);
            app.AtomNumberFitMethodDropDown.Value = 'GaussianFit1D';
            app.TofTab = uitab(app.TabGroup2);
            app.TofTab.Title = 'Tof';
            app.CenterFitTab = uitab(app.TabGroup2);
            app.CenterFitTab.Title = 'CenterFit';
            app.CenterFitMethodDropDownLabel = uilabel(app.CenterFitTab);
            app.CenterFitMethodDropDownLabel.Text = 'Center Fit Method:';
            app.CenterFitMethodDropDown = uidropdown(app.CenterFitTab);
            app.CenterFitMethodDropDown.Items = {'LinearFit1D', 'ParabolicFit1D', 'SineFit1D', 'TriangleFit1D'};
            app.CenterFitMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @CenterFitMethodDropDownValueChanged, true);
            app.CenterFitMethodDropDown.Value = 'LinearFit1D';
            app.SaveCloudCenterCheckBox = uicheckbox(app.CenterFitTab);
            app.SaveCloudCenterCheckBox.ValueChangedFcn = createCallbackFcn(app, @SaveCloudCenterCheckBoxValueChanged, true);
            app.SaveCloudCenterCheckBox.Text = 'Save Cloud Center';
            app.ModifyXThermalFitButton = uibutton(app.CenterFitTab, 'push');
            app.ModifyXThermalFitButton.ButtonPushedFcn = createCallbackFcn(app, @ModifyXThermalFitButtonPushed, true);
            app.ModifyXThermalFitButton.Text = 'Modify X Thermal Fit';
            app.ModifyYThermalFitButton = uibutton(app.CenterFitTab, 'push');
            app.ModifyYThermalFitButton.ButtonPushedFcn = createCallbackFcn(app, @ModifyYThermalFitButtonPushed, true);
            app.ModifyYThermalFitButton.Text = 'Modify Y Thermal Fit';
            app.KapitzaDiracTab = uitab(app.TabGroup2);
            app.KapitzaDiracTab.Title = 'KapitzaDirac';
            app.ScopeValueTab = uitab(app.TabGroup2);
            app.ScopeValueTab.Title = 'ScopeValue';
            app.ScopeValueNameLabel = uilabel(app.ScopeValueTab);
            app.ScopeValueNameLabel.Text = 'Scope Value Name';
            app.ScopeValueNameTextArea = uitextarea(app.ScopeValueTab);
            app.ScopeValueNameTextArea.ValueChangedFcn = createCallbackFcn(app, @ScopeValueNameTextAreaValueChanged, true);
            app.AddNewParameterButton = uibutton(app.BECExperimentPropertiesPanel, 'push');
            app.AddNewParameterButton.ButtonPushedFcn = createCallbackFcn(app, @AddNewParameterButtonPushed, true);
            app.AddNewParameterButton.Text = 'Add New Parameter';
            app.DescriptionTextAreaLabel = uilabel(app.BECExperimentPropertiesPanel);
            app.DescriptionTextAreaLabel.Text = 'Description:';
            app.DescriptionTextArea = uitextarea(app.BECExperimentPropertiesPanel);
            app.DescriptionTextArea.ValueChangedFcn = createCallbackFcn(app, @DescriptionTextAreaValueChanged, true);
            app.CloudCenterReferenceLabel = uilabel(app.BECExperimentPropertiesPanel);
            app.CloudCenterReferenceLabel.Text = 'Cloud Center Reference:';
            app.CloudCenterReferenceDropDown = uidropdown(app.BECExperimentPropertiesPanel);
            app.CloudCenterReferenceDropDown.ValueChangedFcn = createCallbackFcn(app, @CloudCenterReferenceDropDownValueChanged, true);
            app.PotentialConfigurationLabel = uilabel(app.BECExperimentPropertiesPanel);
            app.PotentialConfigurationLabel.Text = 'Potential Configuration:';
            app.PotentialConfigurationDropDown = uidropdown(app.BECExperimentPropertiesPanel);
            app.AveragingMethodDropDownLabel = uilabel(app.BECExperimentPropertiesPanel);
            app.AveragingMethodDropDownLabel.Text = 'Averaging Method';
            app.AveragingMethodDropDown = uidropdown(app.BECExperimentPropertiesPanel);
            app.AveragingMethodDropDown.Items = {'StdErr', 'StdDev', 'None'};
            app.AveragingMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @AveragingMethodDropDownValueChanged, true);
            app.AveragingMethodDropDown.ClickedFcn = createCallbackFcn(app, @AveragingMethodDropDownClicked, true);
            app.AveragingMethodDropDown.Value = 'StdErr';
            app.EditHardwareAssociationButton = uibutton(app.BECExperimentPropertiesPanel, 'push');
            app.EditHardwareAssociationButton.ButtonPushedFcn = createCallbackFcn(app, @EditHardwareAssociationButtonPushed, true);
            app.EditHardwareAssociationButton.Text = 'Edit Hardware Association';
            app.ScannedParameter2Label = uilabel(app.BECExperimentPropertiesPanel);
            app.ScannedParameter2Label.Text = 'Scanned Parameter2:';
            app.ScannedParameter2DropDown = uidropdown(app.BECExperimentPropertiesPanel);
            app.ScannedParameter2DropDown.Editable = 'on';
            app.ScannedParameter2DropDown.ValueChangedFcn = createCallbackFcn(app, @ScannedParameter2DropDownValueChanged, true);
            app.ScannedParameter2DropDown.ClickedFcn = createCallbackFcn(app, @ScannedParameter2DropDownClicked, true);
            app.Panel_2 = uipanel(app.GridLayout2);
            app.RunLogLabel = uilabel(app.Panel_2);
            app.RunLogLabel.Text = 'RunLog:';
            app.RunLogTextArea = uitextarea(app.Panel_2);
            app.RunLogTextArea.Editable = 'off';
            app.OpenROIConfigButton = uibutton(app.Panel_2, 'push');
            app.OpenROIConfigButton.ButtonPushedFcn = createCallbackFcn(app, @OpenROIConfigButtonPushed, true);
            app.OpenROIConfigButton.Text = 'Open ROI Config';
            app.OpenBECExperimentConfigButton = uibutton(app.Panel_2, 'push');
            app.OpenBECExperimentConfigButton.ButtonPushedFcn = createCallbackFcn(app, @OpenBECExperimentConfigButtonPushed, true);
            app.OpenBECExperimentConfigButton.Text = 'Open BEC Experiment Config';
            app.OpenParameterConfigButton = uibutton(app.Panel_2, 'push');
            app.OpenParameterConfigButton.ButtonPushedFcn = createCallbackFcn(app, @OpenParameterConfigButtonPushed, true);
            app.OpenParameterConfigButton.Text = 'Open Parameter Config';
            app.RunSetConfigButton = uibutton(app.Panel_2, 'push');
            app.RunSetConfigButton.ButtonPushedFcn = createCallbackFcn(app, @RunSetConfigButtonPushed, true);
            app.RunSetConfigButton.Text = 'Run SetConfig';
            app.OpenSetConfigButton = uibutton(app.Panel_2, 'push');
            app.OpenSetConfigButton.ButtonPushedFcn = createCallbackFcn(app, @OpenSetConfigButtonPushed, true);
            app.OpenSetConfigButton.Text = 'Open SetConfig';
            app.OpenCiceroOriginButton = uibutton(app.Panel_2, 'push');
            app.OpenCiceroOriginButton.ButtonPushedFcn = createCallbackFcn(app, @OpenCiceroOriginButtonPushed, true);
            app.OpenCiceroOriginButton.Text = 'Open Cicero Origin';
            app.WarningsandErrorsLabel = uilabel(app.Panel_2);
            app.WarningsandErrorsLabel.Text = 'Warnings and Errors:';
            app.WarningsandErrorsTextArea = uitextarea(app.Panel_2);
            app.WarningsandErrorsTextArea.Editable = 'off';
            app.TabGroup = uitabgroup(app.GridLayout);
            app.RunOnsiteExperimentTab = uitab(app.TabGroup);
            app.RunOnsiteExperimentTab.Title = 'Run Onsite Experiment';
            app.UpdateButton = uibutton(app.RunOnsiteExperimentTab, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Enable = 'off';
            app.UpdateButton.Text = 'Update';
            app.RefreshButton = uibutton(app.RunOnsiteExperimentTab, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Enable = 'off';
            app.RefreshButton.Text = 'Refresh';
            app.STARTButton = uibutton(app.RunOnsiteExperimentTab, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.PAUSEButton = uibutton(app.RunOnsiteExperimentTab, 'push');
            app.PAUSEButton.ButtonPushedFcn = createCallbackFcn(app, @PAUSEButtonPushed, true);
            app.PAUSEButton.Enable = 'off';
            app.PAUSEButton.Text = 'PAUSE';
            app.STOPButton = uibutton(app.RunOnsiteExperimentTab, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Enable = 'off';
            app.STOPButton.Text = 'STOP';
            app.OpenDataPathButton = uibutton(app.RunOnsiteExperimentTab, 'push');
            app.OpenDataPathButton.ButtonPushedFcn = createCallbackFcn(app, @OpenDataPathButtonPushed, true);
            app.OpenDataPathButton.Enable = 'off';
            app.OpenDataPathButton.Text = 'Open Data Path';
            app.DeletedRunParameterButton = uibutton(app.RunOnsiteExperimentTab, 'push');
            app.DeletedRunParameterButton.ButtonPushedFcn = createCallbackFcn(app, @DeletedRunParameterButtonPushed, true);
            app.DeletedRunParameterButton.Enable = 'off';
            app.DeletedRunParameterButton.Text = 'Deleted Run Parameter';
            app.RESUMEButton = uibutton(app.RunOnsiteExperimentTab, 'push');
            app.RESUMEButton.ButtonPushedFcn = createCallbackFcn(app, @RESUMEButtonPushed, true);
            app.RESUMEButton.Enable = 'off';
            app.RESUMEButton.Text = 'RESUME';
            app.HoldButton = uibutton(app.RunOnsiteExperimentTab, 'state');
            app.HoldButton.ValueChangedFcn = createCallbackFcn(app, @HoldButtonValueChanged, true);
            app.HoldButton.Enable = 'off';
            app.HoldButton.Text = 'Hold';
            app.FASTSTOPButton = uibutton(app.RunOnsiteExperimentTab, 'push');
            app.FASTSTOPButton.ButtonPushedFcn = createCallbackFcn(app, @FASTSTOPButtonPushed, true);
            app.FASTSTOPButton.Enable = 'off';
            app.FASTSTOPButton.Text = 'FAST STOP';
            app.PlotMixandAnimationButton = uibutton(app.RunOnsiteExperimentTab, 'push');
            app.PlotMixandAnimationButton.ButtonPushedFcn = createCallbackFcn(app, @PlotMixandAnimationButtonPushed, true);
            app.PlotMixandAnimationButton.Enable = 'off';
            app.PlotMixandAnimationButton.Text = 'Plot Mix and Animation';
            app.PlotHistogramofScannedParameterButton = uibutton(app.RunOnsiteExperimentTab, 'push');
            app.PlotHistogramofScannedParameterButton.ButtonPushedFcn = createCallbackFcn(app, @PlotHistogramofScannedParameterButtonPushed, true);
            app.PlotHistogramofScannedParameterButton.Text = 'Plot Histogram of Scanned Parameter';
            app.LoadSavedExperimentTab = uitab(app.TabGroup);
            app.LoadSavedExperimentTab.Title = 'Load Saved Experiment';
            app.StartDateLabel = uilabel(app.LoadSavedExperimentTab);
            app.StartDateLabel.Text = 'Start Date:';
            app.StartDateDatePicker = uidatepicker(app.LoadSavedExperimentTab);
            app.StartDateDatePicker.DisplayFormat = 'uuuu-MM-dd';
            app.StartDateDatePicker.Value = datetime([2024 1 1]);
            app.EndDateLabel = uilabel(app.LoadSavedExperimentTab);
            app.EndDateLabel.Text = 'End Date:';
            app.EndDateDatePicker = uidatepicker(app.LoadSavedExperimentTab);
            app.EndDateDatePicker.DisplayFormat = 'uuuu-MM-dd';
            app.DataTable = uitable(app.LoadSavedExperimentTab);
            app.DataTable.ColumnName = {'SerialNumber'; 'DateTime'; 'Name'; 'ScannedParameter'; 'Description'; 'DataPath'};
            app.DataTable.RowName = {};
            app.DataTable.ColumnSortable = [true true true true false];
            app.DataTable.SelectionType = 'row';
            app.DataTable.ClickedFcn = createCallbackFcn(app, @DataTableClicked, true);
            app.DataTable.Multiselect = 'off';
            app.TrialNameLabel_2 = uilabel(app.LoadSavedExperimentTab);
            app.TrialNameLabel_2.Text = 'Trial Name:';
            app.TrialNameDropDown_2 = uidropdown(app.LoadSavedExperimentTab);
            app.TrialNameDropDown_2.Editable = 'on';
            app.TrialNameDropDown_2.ValueChangedFcn = createCallbackFcn(app, @TrialNameDropDown_2ValueChanged, true);
            app.SerialLabel = uilabel(app.LoadSavedExperimentTab);
            app.SerialLabel.Text = 'Serial #';
            app.SerialEditField = uieditfield(app.LoadSavedExperimentTab, 'numeric');
            app.SerialEditField.Limits = [1 Inf];
            app.SerialEditField.RoundFractionalValues = 'on';
            app.SerialEditField.Enable = 'off';
            app.SerialEditField.Value = 1;
            app.SerialCheckBox = uicheckbox(app.LoadSavedExperimentTab);
            app.SerialCheckBox.ValueChangedFcn = createCallbackFcn(app, @SerialCheckBoxValueChanged, true);
            app.SerialCheckBox.Text = '';
            app.Label = uilabel(app.LoadSavedExperimentTab);
            app.Label.Text = '~';
            app.SerialEditField2 = uieditfield(app.LoadSavedExperimentTab, 'numeric');
            app.SerialEditField2.Limits = [1 Inf];
            app.SerialEditField2.Enable = 'off';
            app.SerialEditField2.Value = 1;
            app.SearchButton = uibutton(app.LoadSavedExperimentTab, 'push');
            app.SearchButton.ButtonPushedFcn = createCallbackFcn(app, @SearchButtonPushed, true);
            app.SearchButton.Text = 'Search';
            app.LoadButton = uibutton(app.LoadSavedExperimentTab, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Enable = 'off';
            app.LoadButton.Text = 'Load';
            app.OpenDataPathButton_3 = uibutton(app.LoadSavedExperimentTab, 'push');
            app.OpenDataPathButton_3.ButtonPushedFcn = createCallbackFcn(app, @OpenDataPathButton_3Pushed, true);
            app.OpenDataPathButton_3.Enable = 'off';
            app.OpenDataPathButton_3.Text = 'Open Data Path';
            app.UnloadButton = uibutton(app.LoadSavedExperimentTab, 'push');
            app.UnloadButton.ButtonPushedFcn = createCallbackFcn(app, @UnloadButtonPushed, true);
            app.UnloadButton.Enable = 'off';
            app.UnloadButton.Text = 'Unload';
            app.RefreshButton_3 = uibutton(app.LoadSavedExperimentTab, 'push');
            app.RefreshButton_3.ButtonPushedFcn = createCallbackFcn(app, @RefreshButton_3Pushed, true);
            app.RefreshButton_3.Enable = 'off';
            app.RefreshButton_3.Text = 'Refresh';
            app.HoldButton_3 = uibutton(app.LoadSavedExperimentTab, 'state');
            app.HoldButton_3.ValueChangedFcn = createCallbackFcn(app, @HoldButton_3ValueChanged, true);
            app.HoldButton_3.Enable = 'off';
            app.HoldButton_3.Text = 'Hold';
            app.KeyWordsEditFieldLabel = uilabel(app.LoadSavedExperimentTab);
            app.KeyWordsEditFieldLabel.Text = 'Key Words:';
            app.KeyWordsEditField = uieditfield(app.LoadSavedExperimentTab, 'text');
            app.ServerButtonGroup = uibuttongroup(app.LoadSavedExperimentTab);
            app.RemoteButton = uitogglebutton(app.ServerButtonGroup);
            app.RemoteButton.Text = 'Remote';
            app.RemoteButton.Value = true;
            app.LocalButton = uitogglebutton(app.ServerButtonGroup);
            app.LocalButton.Text = 'Local';
            app.RunLocalTestTab = uitab(app.TabGroup);
            app.RunLocalTestTab.Title = 'Run Local Test';
            app.UpdateButton_2 = uibutton(app.RunLocalTestTab, 'push');
            app.UpdateButton_2.ButtonPushedFcn = createCallbackFcn(app, @UpdateButton_2Pushed, true);
            app.UpdateButton_2.Enable = 'off';
            app.UpdateButton_2.Text = 'Update';
            app.RefreshButton_2 = uibutton(app.RunLocalTestTab, 'push');
            app.RefreshButton_2.ButtonPushedFcn = createCallbackFcn(app, @RefreshButton_2Pushed, true);
            app.RefreshButton_2.Enable = 'off';
            app.RefreshButton_2.Text = 'Refresh';
            app.STARTButton_2 = uibutton(app.RunLocalTestTab, 'push');
            app.STARTButton_2.ButtonPushedFcn = createCallbackFcn(app, @STARTButton_2Pushed, true);
            app.STARTButton_2.Text = 'START';
            app.PAUSEButton_2 = uibutton(app.RunLocalTestTab, 'push');
            app.PAUSEButton_2.ButtonPushedFcn = createCallbackFcn(app, @PAUSEButton_2Pushed, true);
            app.PAUSEButton_2.Enable = 'off';
            app.PAUSEButton_2.Text = 'PAUSE';
            app.STOPButton_2 = uibutton(app.RunLocalTestTab, 'push');
            app.STOPButton_2.ButtonPushedFcn = createCallbackFcn(app, @STOPButton_2Pushed, true);
            app.STOPButton_2.Enable = 'off';
            app.STOPButton_2.Text = 'STOP';
            app.OpenDataPathButton_2 = uibutton(app.RunLocalTestTab, 'push');
            app.OpenDataPathButton_2.ButtonPushedFcn = createCallbackFcn(app, @OpenDataPathButton_2Pushed, true);
            app.OpenDataPathButton_2.Enable = 'off';
            app.OpenDataPathButton_2.Text = 'Open Data Path';
            app.DeletedRunParameterButton_2 = uibutton(app.RunLocalTestTab, 'push');
            app.DeletedRunParameterButton_2.ButtonPushedFcn = createCallbackFcn(app, @DeletedRunParameterButton_2Pushed, true);
            app.DeletedRunParameterButton_2.Enable = 'off';
            app.DeletedRunParameterButton_2.Text = 'Deleted Run Parameter';
            app.TestDataPathEditField = uieditfield(app.RunLocalTestTab, 'text');
            app.SelectTestDataPathButton = uibutton(app.RunLocalTestTab, 'push');
            app.SelectTestDataPathButton.ButtonPushedFcn = createCallbackFcn(app, @SelectTestDataPathButtonPushed, true);
            app.SelectTestDataPathButton.Text = 'Select Test Data Path';
            app.RunTimeIntervalEditFieldLabel = uilabel(app.RunLocalTestTab);
            app.RunTimeIntervalEditFieldLabel.Text = 'Run Time Interval';
            app.RunTimeIntervalEditField = uieditfield(app.RunLocalTestTab, 'numeric');
            app.secondsLabel = uilabel(app.RunLocalTestTab);
            app.secondsLabel.Text = 'seconds';
            app.RESUMEButton_2 = uibutton(app.RunLocalTestTab, 'push');
            app.RESUMEButton_2.ButtonPushedFcn = createCallbackFcn(app, @RESUMEButton_2Pushed, true);
            app.RESUMEButton_2.Enable = 'off';
            app.RESUMEButton_2.Text = 'RESUME';
            app.HoldButton_2 = uibutton(app.RunLocalTestTab, 'state');
            app.HoldButton_2.ValueChangedFcn = createCallbackFcn(app, @HoldButton_2ValueChanged, true);
            app.HoldButton_2.Enable = 'off';
            app.HoldButton_2.Text = 'Hold';
            app.FASTSTOPButton_2 = uibutton(app.RunLocalTestTab, 'push');
            app.FASTSTOPButton_2.ButtonPushedFcn = createCallbackFcn(app, @FASTSTOPButton_2Pushed, true);
            app.FASTSTOPButton_2.Enable = 'off';
            app.FASTSTOPButton_2.Text = 'FAST STOP';
            app.PlotMixandAnimationButton_2 = uibutton(app.RunLocalTestTab, 'push');
            app.PlotMixandAnimationButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlotMixandAnimationButton_2Pushed, true);
            app.PlotMixandAnimationButton_2.Enable = 'off';
            app.PlotMixandAnimationButton_2.Text = 'Plot Mix and Animation';
            app.MoreFunctionsTab = uitab(app.TabGroup);
            app.MoreFunctionsTab.Title = 'More Functions';
            app.AssignBecExptoWorkSpaceButton = uibutton(app.MoreFunctionsTab, 'push');
            app.AssignBecExptoWorkSpaceButton.ButtonPushedFcn = createCallbackFcn(app, @AssignBecExptoWorkSpaceButtonPushed, true);
            app.AssignBecExptoWorkSpaceButton.Text = 'Assign BecExp to Work-Space';
            app.ResetImageAcquisitionButton = uibutton(app.MoreFunctionsTab, 'push');
            app.ResetImageAcquisitionButton.ButtonPushedFcn = createCallbackFcn(app, @ResetImageAcquisitionButtonPushed, true);
            app.ResetImageAcquisitionButton.Text = 'Reset Image Acquisition';
            app.CreateParallelPoolButton = uibutton(app.MoreFunctionsTab, 'push');
            app.CreateParallelPoolButton.ButtonPushedFcn = createCallbackFcn(app, @CreateParallelPoolButtonPushed, true);
            app.CreateParallelPoolButton.Text = 'Create Parallel Pool';
            % Show the figure after all components are created
            app.BECExperimentControlPanelUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BecControlPanel

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.BECExperimentControlPanelUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.BECExperimentControlPanelUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BECExperimentControlPanelUIFigure)
        end
    end
end